classdef DSF4 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        DSF                             matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        Tab1                            matlab.ui.container.Tab
        T1Lamp1                         matlab.ui.control.Lamp
        T1L1                            matlab.ui.control.Label
        T1F1                            matlab.ui.control.UIAxes
        T1EFN1                          matlab.ui.control.NumericEditField
        T1L2                            matlab.ui.control.Label
        T1S1                            matlab.ui.control.Slider
        SlicedirectionDropDownLabel     matlab.ui.control.Label
        T1DD1                           matlab.ui.control.DropDown
        T1B2                            matlab.ui.control.Button
        T1S2                            matlab.ui.control.Slider
        AzimuthviewangledegreeLabel     matlab.ui.control.Label
        T1EFT5                          matlab.ui.control.TextArea
        ElevationviewangledegreeLabel   matlab.ui.control.Label
        T1EFT6                          matlab.ui.control.TextArea
        RotationaxisLabel               matlab.ui.control.Label
        T1DD2                           matlab.ui.control.DropDown
        RotationangleLabel              matlab.ui.control.Label
        T1DD3                           matlab.ui.control.DropDown
        T1S3                            matlab.ui.control.Slider
        T1B3                            matlab.ui.control.Button
        ImportingposfiledataPanel       matlab.ui.container.Panel
        PosfilenamedonotwriteposLabel   matlab.ui.control.Label
        T1EFT1                          matlab.ui.control.TextArea
        ElementofinterestExampleSiLabel  matlab.ui.control.Label
        T1EFT2                          matlab.ui.control.TextArea
        MasstochargeratiorangeExample139142144146149151Label  matlab.ui.control.Label
        T1EFT3                          matlab.ui.control.TextArea
        MaximumnumberofatomstoplotLabel  matlab.ui.control.Label
        T1EFT4                          matlab.ui.control.TextArea
        T1B1                            matlab.ui.control.Button
        Label                           matlab.ui.control.Label
        T1RB1                           matlab.ui.container.ButtonGroup
        T1RB1Yes                        matlab.ui.control.RadioButton
        T1RB1No                         matlab.ui.control.RadioButton
        T1RB1Hidden                     matlab.ui.control.RadioButton
        T1CB1                           matlab.ui.control.CheckBox
        T1CB2                           matlab.ui.control.CheckBox
        Tab2                            matlab.ui.container.Tab
        T2Lamp1                         matlab.ui.control.Lamp
        T2L1                            matlab.ui.control.Label
        T2F1                            matlab.ui.control.UIAxes
        T2F3                            matlab.ui.control.UIAxes
        T2F2                            matlab.ui.control.UIAxes
        T2F4                            matlab.ui.control.UIAxes
        T2F5                            matlab.ui.control.UIAxes
        T2F6                            matlab.ui.control.UIAxes
        T2EFN1                          matlab.ui.control.NumericEditField
        T2L2                            matlab.ui.control.Label
        T2S1                            matlab.ui.control.Slider
        T2B2                            matlab.ui.control.Button
        T2S2                            matlab.ui.control.Slider
        T2S3                            matlab.ui.control.Slider
        T2B3                            matlab.ui.control.Button
        SlicedirectionDropDownLabel_2   matlab.ui.control.Label
        T2DD1                           matlab.ui.control.DropDown
        RotationaxisLabel_2             matlab.ui.control.Label
        T2DD2                           matlab.ui.control.DropDown
        RotationangleLabel_2            matlab.ui.control.Label
        T2DD3                           matlab.ui.control.DropDown
        T2B0                            matlab.ui.control.Button
        Label_3                         matlab.ui.control.Label
        ParametersettingFirststepPanel  matlab.ui.container.Panel
        MinClusterSizenumberofatomsLabel_2  matlab.ui.control.Label
        T2EFT01                         matlab.ui.control.EditField
        MinSamplesnumberofatomsLabel    matlab.ui.control.Label
        T2EFT02                         matlab.ui.control.EditField
        ProbabilitythresholdExample09Label_2  matlab.ui.control.Label
        T2EFT03                         matlab.ui.control.EditField
        T2B1                            matlab.ui.control.Button
        Label_5                         matlab.ui.control.Label
        T2P2                            matlab.ui.container.Panel
        PersistencescorethresholdEditFieldLabel  matlab.ui.control.Label
        T2EFT2                          matlab.ui.control.EditField
        T2CB1                           matlab.ui.control.CheckBox
        T2CB2                           matlab.ui.control.CheckBox
        T2P2_2                          matlab.ui.container.Panel
        T2B4                            matlab.ui.control.Button
        DenseobjectIDExample137Label    matlab.ui.control.Label
        T2EFT3                          matlab.ui.control.EditField
        T2B5                            matlab.ui.control.Button
        T2B6                            matlab.ui.control.Button
        T2P2_3                          matlab.ui.container.Panel
        IDofdenseobjectstoremoveExample3921Label  matlab.ui.control.Label
        T2EFT4                          matlab.ui.control.EditField
        T2B7                            matlab.ui.control.Button
        Label_4                         matlab.ui.control.Label
        Tab3                            matlab.ui.container.Tab
        T3F1                            matlab.ui.control.UIAxes
        T3Lamp1                         matlab.ui.control.Lamp
        T3L1                            matlab.ui.control.Label
        T3EFN1                          matlab.ui.control.NumericEditField
        T3L2                            matlab.ui.control.Label
        T3S1                            matlab.ui.control.Slider
        T3B3                            matlab.ui.control.Button
        T3S2                            matlab.ui.control.Slider
        T3S3                            matlab.ui.control.Slider
        T3B4                            matlab.ui.control.Button
        SlicedirectionDropDownLabel_3   matlab.ui.control.Label
        T3DD1                           matlab.ui.control.DropDown
        RotationaxisLabel_3             matlab.ui.control.Label
        T3DD2                           matlab.ui.control.DropDown
        RotationangleLabel_3            matlab.ui.control.Label
        T3DD3                           matlab.ui.control.DropDown
        T3B2                            matlab.ui.control.Button
        T3F2                            matlab.ui.control.UIAxes
        T3F3                            matlab.ui.control.UIAxes
        T3P3                            matlab.ui.container.Panel
        T3B7                            matlab.ui.control.Button
        T3P2                            matlab.ui.container.Panel
        ObjectIDExample1273564Label     matlab.ui.control.Label
        T3EFT3                          matlab.ui.control.EditField
        T3B6                            matlab.ui.control.Button
        T3P1                            matlab.ui.container.Panel
        T3B5                            matlab.ui.control.Button
        ObjectIDExample12115Label_2     matlab.ui.control.Label
        T3EFT1                          matlab.ui.control.EditField
        ProbabilitythresholdEditFieldLabel_4  matlab.ui.control.Label
        T3EFT2                          matlab.ui.control.EditField
        T3P4                            matlab.ui.container.Panel
        T3B8                            matlab.ui.control.Button
        ObjectIDandprobabilitythresholdExample1009570979094Label  matlab.ui.control.Label
        T3EFT6                          matlab.ui.control.EditField
        ObjectIDsforstitchingExample1273564Label  matlab.ui.control.Label
        T3EFT7                          matlab.ui.control.EditField
        InformationforpruningExample122015097251108513310094Label  matlab.ui.control.Label
        T3EFT8                          matlab.ui.control.EditField
        Label_7                         matlab.ui.control.Label
        T3CB1                           matlab.ui.control.CheckBox
        T3CB2                           matlab.ui.control.CheckBox
        T3B1                            matlab.ui.control.Button
        Label_6                         matlab.ui.control.Label
        StaticMessage_4                 matlab.ui.control.Label
        Label_8                         matlab.ui.control.Label
        T3Lh1                           matlab.ui.control.Label
        T3Lh2                           matlab.ui.control.Label
        T3Lh3                           matlab.ui.control.Label
        T3F4                            matlab.ui.control.UIAxes
        ObjectIDExample1Label           matlab.ui.control.Label
        T3EFN3                          matlab.ui.control.EditField
        MinClusterSizeeg25Label         matlab.ui.control.Label
        T3EFN4                          matlab.ui.control.EditField
        MinSampleseg15Label             matlab.ui.control.Label
        T3EFN5                          matlab.ui.control.EditField
        Probabilitythresholdeg09Label   matlab.ui.control.Label
        T3EFT5                          matlab.ui.control.EditField
        Tab4                            matlab.ui.container.Tab
        T4F11                           matlab.ui.control.UIAxes
        T4F4                            matlab.ui.control.UIAxes
        T4F1                            matlab.ui.control.UIAxes
        T4F2                            matlab.ui.control.UIAxes
        T4F3                            matlab.ui.control.UIAxes
        T4F5                            matlab.ui.control.UIAxes
        T4Lamp1                         matlab.ui.control.Lamp
        T4L1                            matlab.ui.control.Label
        T4F6                            matlab.ui.control.UIAxes
        T4F10                           matlab.ui.control.UIAxes
        T4F9                            matlab.ui.control.UIAxes
        T4F8                            matlab.ui.control.UIAxes
        T4F7                            matlab.ui.control.UIAxes
        T4F12                           matlab.ui.control.UIAxes
        T4F13                           matlab.ui.control.UIAxes
        T4EFN1                          matlab.ui.control.NumericEditField
        T2L2_2                          matlab.ui.control.Label
        T4S1                            matlab.ui.control.Slider
        T4B3                            matlab.ui.control.Button
        SlicedirectionDropDownLabel_4   matlab.ui.control.Label
        T4DD1                           matlab.ui.control.DropDown
        T4S2                            matlab.ui.control.Slider
        RotationaxisLabel_4             matlab.ui.control.Label
        T4DD2                           matlab.ui.control.DropDown
        RotationangleLabel_4            matlab.ui.control.Label
        T4DD3                           matlab.ui.control.DropDown
        T4S3                            matlab.ui.control.Slider
        T4B4                            matlab.ui.control.Button
        T3P4_2                          matlab.ui.container.Panel
        T4B6                            matlab.ui.control.Button
        T4B5                            matlab.ui.control.Button
        Label_11                        matlab.ui.control.Label
        T4P1                            matlab.ui.container.Panel
        T4B1                            matlab.ui.control.Button
        T4T1                            matlab.ui.control.Table
        T4B2                            matlab.ui.control.Button
        T4CB0                           matlab.ui.control.CheckBox
        Label_9                         matlab.ui.control.Label
        Label_10                        matlab.ui.control.Label
        T4CB2                           matlab.ui.control.CheckBox
        Label_T4F4                      matlab.ui.control.Label
        Label_T4F4_2                    matlab.ui.control.Label
        Label_T4F6_1                    matlab.ui.control.Label
        Label_T4F6_2                    matlab.ui.control.Label
        Label_T4F6_3                    matlab.ui.control.Label
        T4CB1                           matlab.ui.control.CheckBox
        FigurestorotateLabel            matlab.ui.control.Label
        T4DD4                           matlab.ui.control.DropDown
        T4F14                           matlab.ui.control.UIAxes
        T4T2                            matlab.ui.control.Table
        T4L2                            matlab.ui.control.Label
        T4L3                            matlab.ui.control.Label
        T4L4                            matlab.ui.control.Label
        T4L5                            matlab.ui.control.Label
        T4L6                            matlab.ui.control.Label
        T4L7                            matlab.ui.control.Label
        T4L8                            matlab.ui.control.Label
        T4L9                            matlab.ui.control.Label
        T4L10                           matlab.ui.control.Label
        T4L11                           matlab.ui.control.Label
        T4L12                           matlab.ui.control.TextArea
        T4L13                           matlab.ui.control.Label
        T4L14                           matlab.ui.control.Label
        T4L15                           matlab.ui.control.Label
        T4L16                           matlab.ui.control.Label
        T4L18                           matlab.ui.control.Label
        Tab5                            matlab.ui.container.Tab
        T5F1                            matlab.ui.control.UIAxes
        T5F2                            matlab.ui.control.UIAxes
        T5F3                            matlab.ui.control.UIAxes
        T5F4                            matlab.ui.control.UIAxes
        T5F5                            matlab.ui.control.UIAxes
        T5F6                            matlab.ui.control.UIAxes
        T5EFN11                         matlab.ui.control.NumericEditField
        T2L2_3                          matlab.ui.control.Label
        T5S1                            matlab.ui.control.Slider
        T5B9                            matlab.ui.control.Button
        SlicedirectionDropDownLabel_5   matlab.ui.control.Label
        T5DD1                           matlab.ui.control.DropDown
        T5S2                            matlab.ui.control.Slider
        RotationaxisLabel_6             matlab.ui.control.Label
        T5DD2                           matlab.ui.control.DropDown
        RotationangleLabel_5            matlab.ui.control.Label
        T5DD3                           matlab.ui.control.DropDown
        T5S3                            matlab.ui.control.Slider
        T5B10                           matlab.ui.control.Button
        T5Lamp1                         matlab.ui.control.Lamp
        T5L1                            matlab.ui.control.Label
        T5P1                            matlab.ui.container.Panel
        T5B1                            matlab.ui.control.Button
        SkeletonlengththresholdnmLabel  matlab.ui.control.Label
        T5EFT1                          matlab.ui.control.EditField
        T5B2                            matlab.ui.control.Button
        T5EFT2Label                     matlab.ui.control.Label
        T5EFT2                          matlab.ui.control.EditField
        T5B3                            matlab.ui.control.Button
        Label_13                        matlab.ui.control.Label
        T5F7                            matlab.ui.control.UIAxes
        T5T1                            matlab.ui.control.Table
        T5F8                            matlab.ui.control.UIAxes
        T5P5                            matlab.ui.container.Panel
        T5B8                            matlab.ui.control.Button
        T5L12                           matlab.ui.control.Label
        EBSDEuleranglesdegreeExample1515121Label  matlab.ui.control.Label
        T5EFT11                         matlab.ui.control.EditField
        DislocationloopIDExample12Label  matlab.ui.control.Label
        T5EFT12                         matlab.ui.control.EditField
        LEAPEBSDrotationangledegreeLabel  matlab.ui.control.Label
        T5EFT13                         matlab.ui.control.EditField
        T5RB1                           matlab.ui.container.ButtonGroup
        T5RB1cubic                      matlab.ui.control.RadioButton
        T5RB1hcp                        matlab.ui.control.RadioButton
        T5RB1Hidden                     matlab.ui.control.RadioButton
        h_maxEditFieldLabel             matlab.ui.control.Label
        T5EF14                          matlab.ui.control.NumericEditField
        k_maxLabel                      matlab.ui.control.Label
        T5EF15                          matlab.ui.control.NumericEditField
        l_maxLabel                      matlab.ui.control.Label
        T5EF16                          matlab.ui.control.NumericEditField
        StepsizeLabel                   matlab.ui.control.Label
        T5EF17                          matlab.ui.control.NumericEditField
        T5T2                            matlab.ui.control.Table
        T5F11                           matlab.ui.control.UIAxes
        IPFplotforsolutionLabel         matlab.ui.control.Label
        T5EF18                          matlab.ui.control.NumericEditField
        T5P3                            matlab.ui.container.Panel
        T5B4                            matlab.ui.control.Button
        T5B5                            matlab.ui.control.Button
        T5CB1                           matlab.ui.control.CheckBox
        PlotExample13Label              matlab.ui.control.Label
        T5EFT0                          matlab.ui.control.EditField
        T5P4                            matlab.ui.container.Panel
        T5B7                            matlab.ui.control.Button
        T5F9                            matlab.ui.control.UIAxes
        T5F10                           matlab.ui.control.UIAxes
        T5L10                           matlab.ui.control.Label
        T5L11                           matlab.ui.control.Label
        SkeletonIDExample193orallDislocationsorallClustersLabel  matlab.ui.control.Label
        T5EFT10                         matlab.ui.control.EditField
        MindistancenmEditFieldLabel     matlab.ui.control.Label
        T5EFT6                          matlab.ui.control.EditField
        MaxdistancenmLabel              matlab.ui.control.Label
        T5EFT7                          matlab.ui.control.EditField
        StepsizenmLabel                 matlab.ui.control.Label
        T5EFT8                          matlab.ui.control.EditField
        T5L2                            matlab.ui.control.Label
        T5L3                            matlab.ui.control.Label
        T5L4                            matlab.ui.control.Label
        T5L5                            matlab.ui.control.Label
        T5L6                            matlab.ui.control.Label
        T5L7                            matlab.ui.control.Label
        T5L8                            matlab.ui.control.Label
        T5L9                            matlab.ui.control.Label
        Label_12                        matlab.ui.control.Label
        T5P2                            matlab.ui.container.Panel
        IDofskeletonstoconsiderasdislocationsExample147EditFieldLabel  matlab.ui.control.Label
        T5EFT3                          matlab.ui.control.EditField
        IDofskeletonstoconsiderasclustersExample258Label  matlab.ui.control.Label
        T5EFT4                          matlab.ui.control.EditField
        IDofskeletonstoconsiderasothersExample369Label  matlab.ui.control.Label
        T5EFT5                          matlab.ui.control.EditField
        T5B6                            matlab.ui.control.Button
        Label_14                        matlab.ui.control.Label
        T5CB2                           matlab.ui.control.CheckBox
        T5CB3                           matlab.ui.control.CheckBox
        T5P6                            matlab.ui.container.Panel
        DislocationloopIDvaluesExample39Label  matlab.ui.control.Label
        T5EFT14                         matlab.ui.control.EditField
        T5B11                           matlab.ui.control.Button
        T5P8                            matlab.ui.container.Panel
        DislocationloopIDvaluesExample24567EditFieldLabel  matlab.ui.control.Label
        T5EFT15                         matlab.ui.control.EditField
        T5B12                           matlab.ui.control.Button
        PlottitleoptionalEditFieldLabel  matlab.ui.control.Label
        T5EFT16                         matlab.ui.control.EditField
        Tab6                            matlab.ui.container.Tab
        T6P0                            matlab.ui.container.Panel
        T6F1                            matlab.ui.control.UIAxes
        T6F2                            matlab.ui.control.UIAxes
        T6F3                            matlab.ui.control.UIAxes
        T6F4                            matlab.ui.control.UIAxes
        T6F5                            matlab.ui.control.UIAxes
        T6L11                           matlab.ui.control.Label
        T6L12                           matlab.ui.control.Label
        T6L13                           matlab.ui.control.Label
        T6L15                           matlab.ui.control.Label
        T6F4_2                          matlab.ui.control.UIAxes
        T6L6                            matlab.ui.control.Label
        T6L2                            matlab.ui.control.Label
        T6L3                            matlab.ui.control.Label
        T6L4                            matlab.ui.control.Label
        T6L5                            matlab.ui.control.Label
        T6B1                            matlab.ui.control.Button
        T6B2                            matlab.ui.control.Button
        T6Lamp1                         matlab.ui.control.Lamp
        T6L1                            matlab.ui.control.Label
        T6P1                            matlab.ui.container.Panel
        PointsizeLabel                  matlab.ui.control.Label
        T6EFT1                          matlab.ui.control.EditField
        PointcolorLabel                 matlab.ui.control.Label
        T6EFT2                          matlab.ui.control.EditField
        FontsizeLabel                   matlab.ui.control.Label
        T6EFT3                          matlab.ui.control.EditField
        FonttitlemultiplierLabel        matlab.ui.control.Label
        T6EFT4                          matlab.ui.control.EditField
        T6CB2                           matlab.ui.control.CheckBox
        T6CB4                           matlab.ui.control.CheckBox
        ScalebarpositionnmLabel         matlab.ui.control.Label
        T6EFT5                          matlab.ui.control.EditField
        ScalebarsizenmLabel             matlab.ui.control.Label
        T6EFT6                          matlab.ui.control.EditField
        ScalebartextpositionnmLabel     matlab.ui.control.Label
        T6EFT7                          matlab.ui.control.EditField
        ScalebartextfontLabel           matlab.ui.control.Label
        T6EFT8                          matlab.ui.control.EditField
        T6BG1                           matlab.ui.container.ButtonGroup
        T6BG6_Intrinsic                 matlab.ui.control.RadioButton
        T6BG6_Fixed                     matlab.ui.control.RadioButton
        T6BG6_None                      matlab.ui.control.RadioButton
        T6CB30                          matlab.ui.control.CheckBox
        T6P6                            matlab.ui.container.Panel
        T6B3                            matlab.ui.control.Button
        FromplotLabel                   matlab.ui.control.Label
        T6EF1                           matlab.ui.control.NumericEditField
        toplotLabel                     matlab.ui.control.Label
        T6EF2                           matlab.ui.control.NumericEditField
        LeftmarginpixelLabel            matlab.ui.control.Label
        T6EF3                           matlab.ui.control.NumericEditField
        RightmarginpixelLabel           matlab.ui.control.Label
        T6EF4                           matlab.ui.control.NumericEditField
        TopmarginpixelLabel             matlab.ui.control.Label
        T6EF5                           matlab.ui.control.NumericEditField
        BottommarginpixelLabel          matlab.ui.control.Label
        T6EF6                           matlab.ui.control.NumericEditField
        Framerate1sLabel                matlab.ui.control.Label
        T6EF7                           matlab.ui.control.NumericEditField
        VideoqualityLabel               matlab.ui.control.Label
        T6EF8                           matlab.ui.control.NumericEditField
        NumberofcyclesLabel             matlab.ui.control.Label
        T6EFT26                         matlab.ui.control.EditField
        T6EFT27Label                    matlab.ui.control.Label
        T6EFT27                         matlab.ui.control.EditField
        T6BG6                           matlab.ui.container.ButtonGroup
        T6BG6_Rotation                  matlab.ui.control.RadioButton
        T6BG6_Slice                     matlab.ui.control.RadioButton
        aLabel                          matlab.ui.control.Label
        BackgroundcolorEditFieldLabel   matlab.ui.control.Label
        T6EFT28                         matlab.ui.control.EditField
        T6DD1_2Label                    matlab.ui.control.Label
        T6DD1_2                         matlab.ui.control.DropDown
        T6DD2_2Label                    matlab.ui.control.Label
        T6DD2_2                         matlab.ui.control.DropDown
        T6DD3_2Label                    matlab.ui.control.Label
        T6DD3_2                         matlab.ui.control.DropDown
        T6P2                            matlab.ui.container.Panel
        SkeletonpointsizeLabel          matlab.ui.control.Label
        T6EFT9                          matlab.ui.control.EditField
        OriginalpointsizeLabel          matlab.ui.control.Label
        T6EFT10                         matlab.ui.control.EditField
        FontsizeLabel_2                 matlab.ui.control.Label
        T6EFT11                         matlab.ui.control.EditField
        FonttitlemultiplierLabel_2      matlab.ui.control.Label
        T6EFT12                         matlab.ui.control.EditField
        T6CB7                           matlab.ui.control.CheckBox
        ScalebarpositionnmLabel_2       matlab.ui.control.Label
        T6EFT13                         matlab.ui.control.EditField
        ScalebarsizenmLabel_2           matlab.ui.control.Label
        T6EFT14                         matlab.ui.control.EditField
        ScalebartextpositionnmLabel_2   matlab.ui.control.Label
        T6EFT16                         matlab.ui.control.EditField
        ScalebartextfontLabel_2         matlab.ui.control.Label
        T6EFT15                         matlab.ui.control.EditField
        T6CB5                           matlab.ui.control.CheckBox
        T6CB6                           matlab.ui.control.CheckBox
        T6CB10                          matlab.ui.control.CheckBox
        T6CB9                           matlab.ui.control.CheckBox
        T6P3                            matlab.ui.container.Panel
        SkeletonpointsizeLabel_2        matlab.ui.control.Label
        T6EFT9_2                        matlab.ui.control.EditField
        OriginalpointsizeLabel_2        matlab.ui.control.Label
        T6EFT10_2                       matlab.ui.control.EditField
        FontsizeLabel_3                 matlab.ui.control.Label
        T6EFT11_2                       matlab.ui.control.EditField
        FonttitlemultiplierLabel_3      matlab.ui.control.Label
        T6EFT12_2                       matlab.ui.control.EditField
        T6CB7_2                         matlab.ui.control.CheckBox
        T6CB9_2                         matlab.ui.control.CheckBox
        ScalebarpositionnmLabel_3       matlab.ui.control.Label
        T6EFT13_2                       matlab.ui.control.EditField
        ScalebarsizenmLabel_3           matlab.ui.control.Label
        T6EFT14_2                       matlab.ui.control.EditField
        ScalebartextpositionnmLabel_3   matlab.ui.control.Label
        T6EFT16_2                       matlab.ui.control.EditField
        ScalebartextfontLabel_3         matlab.ui.control.Label
        T6EFT15_2                       matlab.ui.control.EditField
        T6CB5_2                         matlab.ui.control.CheckBox
        T6CB6_2                         matlab.ui.control.CheckBox
        T6CB10_2                        matlab.ui.control.CheckBox
        T6BG3                           matlab.ui.container.ButtonGroup
        T6BG3_Intrinsic                 matlab.ui.control.RadioButton
        T6BG3_Fixed                     matlab.ui.control.RadioButton
        T6BG3_None                      matlab.ui.control.RadioButton
        T6P5                            matlab.ui.container.Panel
        SkeletonpointsizeLabel_3        matlab.ui.control.Label
        T6EFT9_3                        matlab.ui.control.EditField
        OriginalpointsizeLabel_3        matlab.ui.control.Label
        T6EFT10_3                       matlab.ui.control.EditField
        FontsizeLabel_4                 matlab.ui.control.Label
        T6EFT11_3                       matlab.ui.control.EditField
        FonttitlemultiplierLabel_4      matlab.ui.control.Label
        T6EFT12_3                       matlab.ui.control.EditField
        T6CB7_3                         matlab.ui.control.CheckBox
        T6CB9_3                         matlab.ui.control.CheckBox
        ScalebarpositionnmLabel_4       matlab.ui.control.Label
        T6EFT13_3                       matlab.ui.control.EditField
        ScalebarsizenmLabel_4           matlab.ui.control.Label
        T6EFT14_3                       matlab.ui.control.EditField
        ScalebartextpositionnmLabel_4   matlab.ui.control.Label
        T6EFT16_3                       matlab.ui.control.EditField
        ScalebartextfontLabel_4         matlab.ui.control.Label
        T6EFT15_3                       matlab.ui.control.EditField
        T6CB5_3                         matlab.ui.control.CheckBox
        T6CB6_3                         matlab.ui.control.CheckBox
        T6CB10_3                        matlab.ui.control.CheckBox
        T6BG5                           matlab.ui.container.ButtonGroup
        T6BG5_Intrinsic                 matlab.ui.control.RadioButton
        T6BG5_Fixed                     matlab.ui.control.RadioButton
        T6BG5_None                      matlab.ui.control.RadioButton
        T6P4                            matlab.ui.container.Panel
        DislocationpointsizeLabel       matlab.ui.control.Label
        T6EFT17_1                       matlab.ui.control.EditField
        DislocationpointcolorLabel      matlab.ui.control.Label
        T6EFT18_1                       matlab.ui.control.EditField
        FontsizeLabel_5                 matlab.ui.control.Label
        T6EFT20                         matlab.ui.control.EditField
        FonttitlemultiplierLabel_5      matlab.ui.control.Label
        T6EFT21                         matlab.ui.control.EditField
        T6CB14                          matlab.ui.control.CheckBox
        T6CB15                          matlab.ui.control.CheckBox
        T6CB16                          matlab.ui.control.CheckBox
        ScalebarpositionnmLabel_5       matlab.ui.control.Label
        T6EFT22                         matlab.ui.control.EditField
        ScalebarsizenmLabel_5           matlab.ui.control.Label
        T6EFT23                         matlab.ui.control.EditField
        ScalebartextpositionnmLabel_5   matlab.ui.control.Label
        T6EFT25                         matlab.ui.control.EditField
        ScalebartextfontLabel_5         matlab.ui.control.Label
        T6EFT24                         matlab.ui.control.EditField
        T6CB11                          matlab.ui.control.CheckBox
        T6CB12                          matlab.ui.control.CheckBox
        ClusterpointsizeLabel           matlab.ui.control.Label
        T6EFT17_2                       matlab.ui.control.EditField
        ClusterpointcolorLabel          matlab.ui.control.Label
        T6EFT18_2                       matlab.ui.control.EditField
        T6CB13                          matlab.ui.control.CheckBox
        OtherobjectpointsizeLabel       matlab.ui.control.Label
        T6EFT17_3                       matlab.ui.control.EditField
        OtherobjectpointcolorLabel      matlab.ui.control.Label
        T6EFT18_3                       matlab.ui.control.EditField
        T6BG4                           matlab.ui.container.ButtonGroup
        T6BG4_Intrinsic                 matlab.ui.control.RadioButton
        T6BG4_Fixed                     matlab.ui.control.RadioButton
        T6BG4_None                      matlab.ui.control.RadioButton
        T6EFN11                         matlab.ui.control.NumericEditField
        T2L2_4                          matlab.ui.control.Label
        T6S1                            matlab.ui.control.Slider
        T6B9                            matlab.ui.control.Button
        SlicedirectionDropDownLabel_6   matlab.ui.control.Label
        T6DD1                           matlab.ui.control.DropDown
        T6S2                            matlab.ui.control.Slider
        RotationaxisLabel_7             matlab.ui.control.Label
        T6DD2                           matlab.ui.control.DropDown
        RotationangleLabel_6            matlab.ui.control.Label
        T6DD3                           matlab.ui.control.DropDown
        T6S3                            matlab.ui.control.Slider
        T6B10                           matlab.ui.control.Button
        T6BG2                           matlab.ui.container.ButtonGroup
        T6BG2_Intrinsic                 matlab.ui.control.RadioButton
        T6BG2_Fixed                     matlab.ui.control.RadioButton
        T6BG2_None                      matlab.ui.control.RadioButton
        T6P7                            matlab.ui.container.Panel
        T6CB1                           matlab.ui.control.CheckBox
        T6B4                            matlab.ui.control.Button
        bLabel                          matlab.ui.control.Label
        PlotExample13EditFieldLabel     matlab.ui.control.Label
        T6EFT0                          matlab.ui.control.EditField
        Label_15                        matlab.ui.control.Label
        Label_16                        matlab.ui.control.Label
        Label_17                        matlab.ui.control.Label
        T6B11                           matlab.ui.control.Button
    end

    
    properties (Access = private)
        % Description
    end
    
    properties (Access = public)
        AxisLimits
        handleT2F4
        hdbscanSelByPersisAndProb
        handleT3F2
        hdb
        Message
        Skeleton
        handleT4F12
        Interest
        OtherAtoms
        handleT5F2
        Clusters
        Dislocations
        Others
        MTEXisLoaded %I do not make this variable emptry in the beginning of each tab
        
        T1S2previousValue
        T2S2previousValue
        T3S2previousValue
        T4S2previousValue
        T5S2previousValue
        T6S2previousValue
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            StartUpFunction(app)
        end

        % Button pushed function: T1B1
        function T1B1ButtonPushed(app, event)
            T1B1(app)
        end

        % Value changed function: T1S1
        function T1S1ValueChanged(app, event)
            T1S1(app)
        end

        % Value changed function: T1DD1
        function T1DD1ValueChanged(app, event)
            T1DD1(app)
        end

        % Button pushed function: T1B2
        function T1B2ButtonPushed(app, event)
            T1B2(app)
        end

        % Button pushed function: T1B3
        function T1B3ButtonPushed(app, event)
            T1B3(app)
        end

        % Value changed function: T2S1
        function T2S1ValueChanged(app, event)
            T2S1(app)
        end

        % Value changed function: T2DD1
        function T2DD1ValueChanged(app, event)
            T2DD1(app)
        end

        % Button pushed function: T2B2
        function T2B2ButtonPushed(app, event)
            T2B2(app)
        end

        % Value changed function: T2S2
        function T2S2ValueChanged(app, event)
            T2S2(app)
        end

        % Value changed function: T2S3
        function T2S3ValueChanged(app, event)
            T2S3(app)
        end

        % Button pushed function: T2B3
        function T2B3ButtonPushed(app, event)
            T2B3(app)
        end

        % Button pushed function: T2B4
        function T2B4ButtonPushed(app, event)
            T2B4(app)
        end

        % Button pushed function: T2B5
        function T2B5ButtonPushed(app, event)
            T2B5(app)
        end

        % Button pushed function: T2B6
        function T2B6ButtonPushed(app, event)
            T2B6(app)
        end

        % Button pushed function: T2B7
        function T2B7ButtonPushed(app, event)
            T2B7(app)
        end

        % Button pushed function: T3B1
        function T3B1ButtonPushed(app, event)
            T3B1(app)
        end

        % Button pushed function: T3B2
        function T3B2ButtonPushed(app, event)
            T3B2(app)
        end

        % Value changed function: T3S1
        function T3S1ValueChanged(app, event)
            T3S1(app)
        end

        % Button pushed function: T3B3
        function T3B3ButtonPushed(app, event)
            T3B3(app)
        end

        % Value changed function: T3S2
        function T3S2ValueChanged(app, event)
            T3S2(app)
        end

        % Value changed function: T3S3
        function T3S3ValueChanged(app, event)
            T3S3(app)
        end

        % Value changed function: T3DD1
        function T3DD1ValueChanged(app, event)
            T3DD1(app)
        end

        % Button pushed function: T3B4
        function T3B4ButtonPushed(app, event)
            T3B4(app)
        end

        % Button pushed function: T3B5
        function T3B5ButtonPushed(app, event)
            T3B5(app)
        end

        % Button pushed function: T3B6
        function T3B6ButtonPushed(app, event)
            T3B6(app)
        end

        % Button pushed function: T3B7
        function T3B7ButtonPushed(app, event)
            T3B7(app)
        end

        % Button pushed function: T3B8
        function T3B8ButtonPushed(app, event)
            T3B8(app)
        end

        % Button pushed function: T4B1
        function T4B1ButtonPushed(app, event)
            T4B1(app)
        end

        % Button pushed function: T4B2
        function T4B2ButtonPushed(app, event)
            T4B2(app)
        end

        % Value changed function: T4S1
        function T4S1ValueChanged(app, event)
            T4S1(app)
        end

        % Button pushed function: T4B3
        function T4B3ButtonPushed(app, event)
            T4B3(app)
        end

        % Value changed function: T4S2
        function T4S2ValueChanged(app, event)
            T4S2(app)
        end

        % Value changed function: T4S3
        function T4S3ValueChanged(app, event)
            T4S3(app)
        end

        % Button pushed function: T4B4
        function T4B4ButtonPushed(app, event)
            T4B4(app)
        end

        % Button pushed function: T4B5
        function T4B5ButtonPushed(app, event)
            T4B5(app)
        end

        % Button pushed function: T4B6
        function T4B6ButtonPushed(app, event)
            T4B6(app)
        end

        % Button pushed function: T5B1
        function T5B1ButtonPushed(app, event)
            T5B1(app)
        end

        % Button pushed function: T5B2
        function T5B2ButtonPushed(app, event)
            T5B2(app)
        end

        % Button pushed function: T5B3
        function T5B3ButtonPushed(app, event)
            T5B3(app)
        end

        % Button pushed function: T5B4
        function T5B4ButtonPushed(app, event)
            T5B4(app)
        end

        % Value changed function: T5CB1
        function T5CB1ValueChanged(app, event)
            T5CB1(app)
        end

        % Button pushed function: T5B7
        function T5B7ButtonPushed(app, event)
            T5B7(app)
        end

        % Button pushed function: T5B8
        function T5B8ButtonPushed(app, event)
            T5B8(app)
        end

        % Value changed function: T5S1
        function T5S1ValueChanged(app, event)
            T5S1(app)
        end

        % Value changed function: T5DD1
        function T5DD1ValueChanged(app, event)
            T5DD1(app)
        end

        % Button pushed function: T5B9
        function T5B9ButtonPushed(app, event)
            T5B9(app)
        end

        % Value changed function: T5S2
        function T5S2ValueChanged(app, event)
            T5S2(app)
        end

        % Value changed function: T5S3
        function T5S3ValueChanged(app, event)
            T5S3(app)
        end

        % Button pushed function: T5B10
        function T5B10ButtonPushed(app, event)
            T5B10(app)
        end

        % Value changed function: T6CB1
        function T6CB1ValueChanged(app, event)
            T6CB1(app)
        end

        % Button pushed function: T6B1
        function T6B1ButtonPushed(app, event)
            T6B1(app)
        end

        % Button pushed function: T6B2
        function T6B2ButtonPushed(app, event)
            T6B2(app)
        end

        % Button pushed function: T6B3
        function T6B3ButtonPushed(app, event)
            T6B3(app)
        end

        % Value changed function: T6DD1
        function T6DD1ValueChanged(app, event)
            T6DD1(app)
        end

        % Button pushed function: T6B9
        function T6B9ButtonPushed(app, event)
            T6B9(app)
        end

        % Value changed function: T6S1
        function T6S1ValueChanged(app, event)
            T6S1(app)
        end

        % Value changed function: T6S2
        function T6S2ValueChanged(app, event)
            T6S2(app)
        end

        % Button pushed function: T6B10
        function T6B10ButtonPushed(app, event)
            T6B10(app)
        end

        % Value changed function: T6S3
        function T6S3ValueChanged(app, event)
            T6S3(app)
        end

        % Selection changed function: T6BG6
        function T6BG6SelectionChanged(app, event)
            T6BG6(app)
        end

        % Selection changed function: T6BG1
        function T6BG1SelectionChanged(app, event)
            T6BG1(app)
        end

        % Selection changed function: T6BG2
        function T6BG2SelectionChanged(app, event)
            T6BG2(app)
        end

        % Selection changed function: T6BG3
        function T6BG3SelectionChanged(app, event)
            T6BG3(app)
        end

        % Selection changed function: T6BG4
        function T6BG4SelectionChanged(app, event)
            T6BG4(app)
        end

        % Selection changed function: T6BG5
        function T6BG5SelectionChanged(app, event)
            T6BG5(app)
        end

        % Value changed function: T1S2
        function T1S2ValueChanged(app, event)
            T1S2(app)
        end

        % Button pushed function: T2B0
        function T2B0ButtonPushed(app, event)
            T2B0(app)
        end

        % Button pushed function: T2B1
        function T2B1ButtonPushed(app, event)
            T2B1(app)
        end

        % Value changed function: T2CB2
        function T2CB2ValueChanged(app, event)
            T2CB2(app)
        end

        % Value changed function: T3CB1
        function T3CB1ValueChanged(app, event)
            T3CB1(app)
        end

        % Value changed function: T4CB2
        function T4CB2ValueChanged(app, event)
            T4CB2(app)
        end

        % Value changed function: T4CB0
        function T4CB0ValueChanged(app, event)
            T4CB0(app)
        end

        % Value changed function: T4DD1
        function T4DD1ValueChanged(app, event)
            T4DD1(app)
        end

        % Button pushed function: T5B5
        function T5B5ButtonPushed(app, event)
            T5B5(app)
        end

        % Button pushed function: T6B4
        function T6B4ButtonPushed(app, event)
            T6B4(app)
        end

        % Value changed function: T1S3
        function T1S3ValueChanged(app, event)
            T1S3(app)
        end

        % Button pushed function: T5B6
        function T5B6ButtonPushed(app, event)
            T5B6(app)
        end

        % Value changed function: T5CB3
        function T5CB3ValueChanged(app, event)
            T5CB3(app)
        end

        % Button pushed function: T5B11
        function T5B11ButtonPushed(app, event)
            T5B11(app)
        end

        % Button pushed function: T5B12
        function T5B12ButtonPushed(app, event)
            T5B12(app)
        end

        % Button pushed function: T6B11
        function T6B11ButtonPushed(app, event)
            T6B11(app)
        end

        % Value changed function: T6EFT28
        function T6EFT28ValueChanged(app, event)
            T6EFT28(app)
        end

        % Value changed function: T1CB1
        function T1CB1ValueChanged(app, event)
            T1CB1(app)
        end

        % Value changed function: T1CB2
        function T1CB2ValueChanged(app, event)
            T1CB2(app)
        end

        % Value changed function: T4CB1
        function T4CB1ValueChanged(app, event)
            T4CB1(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.DSF = uifigure('Visible', 'off');
            app.DSF.Name = 'Dislocation Skeleton Finder (DSF)';
            app.TabGroup = uitabgroup(app.DSF);
            app.Tab1 = uitab(app.TabGroup);
            app.Tab1.Title = '(1) Importing pos file';
            app.T1Lamp1 = uilamp(app.Tab1);
            app.T1L1 = uilabel(app.Tab1);
            app.T1L1.Text = {'DSF interface is loading. '; 'Please wait!'};
            app.T1F1 = uiaxes(app.Tab1);
            title(app.T1F1, 'Title')
            xlabel(app.T1F1, 'X')
            ylabel(app.T1F1, 'Y')
            app.T1F1.Visible = 'off';
            app.T1EFN1 = uieditfield(app.Tab1, 'numeric');
            app.T1EFN1.Value = 8;
            app.T1L2 = uilabel(app.Tab1);
            app.T1L2.Text = 'Slice thickness (nm)';
            app.T1S1 = uislider(app.Tab1);
            app.T1S1.Limits = [0 1];
            app.T1S1.ValueChangedFcn = createCallbackFcn(app, @T1S1ValueChanged, true);
            app.T1S1.Value = 0.5;
            app.SlicedirectionDropDownLabel = uilabel(app.Tab1);
            app.SlicedirectionDropDownLabel.Text = 'Slice direction';
            app.T1DD1 = uidropdown(app.Tab1);
            app.T1DD1.Items = {'x-axis', 'y-axis', 'z-axis'};
            app.T1DD1.ValueChangedFcn = createCallbackFcn(app, @T1DD1ValueChanged, true);
            app.T1DD1.Value = 'x-axis';
            app.T1B2 = uibutton(app.Tab1, 'push');
            app.T1B2.ButtonPushedFcn = createCallbackFcn(app, @T1B2ButtonPushed, true);
            app.T1B2.Text = 'Reset';
            app.T1S2 = uislider(app.Tab1);
            app.T1S2.Limits = [0 360];
            app.T1S2.ValueChangedFcn = createCallbackFcn(app, @T1S2ValueChanged, true);
            app.AzimuthviewangledegreeLabel = uilabel(app.Tab1);
            app.AzimuthviewangledegreeLabel.Text = 'Azimuth view angle (degree)';
            app.T1EFT5 = uitextarea(app.Tab1);
            app.T1EFT5.Value = {'36'};
            app.ElevationviewangledegreeLabel = uilabel(app.Tab1);
            app.ElevationviewangledegreeLabel.Text = 'Elevation view angle (degree)';
            app.T1EFT6 = uitextarea(app.Tab1);
            app.T1EFT6.Value = {'18'};
            app.RotationaxisLabel = uilabel(app.Tab1);
            app.RotationaxisLabel.Text = 'Rotation axis';
            app.T1DD2 = uidropdown(app.Tab1);
            app.T1DD2.Items = {'x-axis', 'y-axis', 'z-axis'};
            app.T1DD2.Value = 'z-axis';
            app.RotationangleLabel = uilabel(app.Tab1);
            app.RotationangleLabel.Text = 'Rotation angle';
            app.T1DD3 = uidropdown(app.Tab1);
            app.T1DD3.Items = {'Azimuth', 'Elevation', 'Azimuth_Reverse', 'Elevation_Reverse', ''};
            app.T1DD3.Value = 'Azimuth';
            app.T1S3 = uislider(app.Tab1);
            app.T1S3.Limits = [0.7 2];
            app.T1S3.ValueChangedFcn = createCallbackFcn(app, @T1S3ValueChanged, true);
            app.T1S3.Value = 1;
            app.T1B3 = uibutton(app.Tab1, 'push');
            app.T1B3.ButtonPushedFcn = createCallbackFcn(app, @T1B3ButtonPushed, true);
            app.T1B3.Text = 'Reset zoom';
            app.ImportingposfiledataPanel = uipanel(app.Tab1);
            app.ImportingposfiledataPanel.Title = 'Importing pos file data';
            app.PosfilenamedonotwriteposLabel = uilabel(app.ImportingposfiledataPanel);
            app.PosfilenamedonotwriteposLabel.Text = 'Pos file name (do not write .pos)';
            app.T1EFT1 = uitextarea(app.ImportingposfiledataPanel);
            app.ElementofinterestExampleSiLabel = uilabel(app.ImportingposfiledataPanel);
            app.ElementofinterestExampleSiLabel.Text = 'Element of interest (Example: Si)';
            app.T1EFT2 = uitextarea(app.ImportingposfiledataPanel);
            app.MasstochargeratiorangeExample139142144146149151Label = uilabel(app.ImportingposfiledataPanel);
            app.MasstochargeratiorangeExample139142144146149151Label.Text = {'Mass to charge ratio range of the elements of interest'; 'Example: 13.9,14.2;14.4,14.6;14.9,15.1'; ''; ''};
            app.T1EFT3 = uitextarea(app.ImportingposfiledataPanel);
            app.MaximumnumberofatomstoplotLabel = uilabel(app.ImportingposfiledataPanel);
            app.MaximumnumberofatomstoplotLabel.Text = {'Maximum number of atoms to plot'; ''; ''};
            app.T1EFT4 = uitextarea(app.ImportingposfiledataPanel);
            app.T1EFT4.Value = {'2500000'};
            app.T1B1 = uibutton(app.ImportingposfiledataPanel, 'push');
            app.T1B1.ButtonPushedFcn = createCallbackFcn(app, @T1B1ButtonPushed, true);
            app.T1B1.Text = 'Import pos file';
            app.Label = uilabel(app.ImportingposfiledataPanel);
            app.Label.Text = '(1)';
            app.T1RB1 = uibuttongroup(app.ImportingposfiledataPanel);
            app.T1RB1.Title = 'Was "Reverse Z" checked during the reconstruction?';
            app.T1RB1Yes = uiradiobutton(app.T1RB1);
            app.T1RB1Yes.Text = 'Yes';
            app.T1RB1No = uiradiobutton(app.T1RB1);
            app.T1RB1No.Text = 'No';
            app.T1RB1Hidden = uiradiobutton(app.T1RB1);
            app.T1RB1Hidden.Visible = 'off';
            app.T1RB1Hidden.Text = 'Hidden';
            app.T1RB1Hidden.Value = true;
            app.T1CB1 = uicheckbox(app.ImportingposfiledataPanel);
            app.T1CB1.ValueChangedFcn = createCallbackFcn(app, @T1CB1ValueChanged, true);
            app.T1CB1.Text = 'Show help';
            app.T1CB2 = uicheckbox(app.ImportingposfiledataPanel);
            app.T1CB2.ValueChangedFcn = createCallbackFcn(app, @T1CB2ValueChanged, true);
            app.T1CB2.Text = 'Show help';
            app.Tab2 = uitab(app.TabGroup);
            app.Tab2.Title = '(2/1) Detecting dense objects (removing noise)';
            app.T2Lamp1 = uilamp(app.Tab2);
            app.T2L1 = uilabel(app.Tab2);
            app.T2L1.Text = {''; ''};
            app.T2F1 = uiaxes(app.Tab2);
            title(app.T2F1, 'Title')
            xlabel(app.T2F1, 'X')
            ylabel(app.T2F1, 'Y')
            app.T2F1.Visible = 'off';
            app.T2F3 = uiaxes(app.Tab2);
            title(app.T2F3, 'Title')
            xlabel(app.T2F3, 'X')
            ylabel(app.T2F3, 'Y')
            app.T2F3.Visible = 'off';
            app.T2F2 = uiaxes(app.Tab2);
            title(app.T2F2, 'Title')
            xlabel(app.T2F2, 'X')
            ylabel(app.T2F2, 'Y')
            app.T2F2.Visible = 'off';
            app.T2F4 = uiaxes(app.Tab2);
            title(app.T2F4, 'Title')
            xlabel(app.T2F4, 'X')
            ylabel(app.T2F4, 'Y')
            app.T2F4.Visible = 'off';
            app.T2F5 = uiaxes(app.Tab2);
            title(app.T2F5, 'Title')
            xlabel(app.T2F5, 'X')
            ylabel(app.T2F5, 'Y')
            app.T2F5.Visible = 'off';
            app.T2F6 = uiaxes(app.Tab2);
            title(app.T2F6, 'Title')
            xlabel(app.T2F6, 'X')
            ylabel(app.T2F6, 'Y')
            app.T2F6.Visible = 'off';
            app.T2EFN1 = uieditfield(app.Tab2, 'numeric');
            app.T2EFN1.Value = 8;
            app.T2L2 = uilabel(app.Tab2);
            app.T2L2.Text = 'Slice thickness (nm)';
            app.T2S1 = uislider(app.Tab2);
            app.T2S1.Limits = [0 1];
            app.T2S1.ValueChangedFcn = createCallbackFcn(app, @T2S1ValueChanged, true);
            app.T2S1.Value = 0.5;
            app.T2B2 = uibutton(app.Tab2, 'push');
            app.T2B2.ButtonPushedFcn = createCallbackFcn(app, @T2B2ButtonPushed, true);
            app.T2B2.Text = 'Reset';
            app.T2S2 = uislider(app.Tab2);
            app.T2S2.Limits = [0 360];
            app.T2S2.ValueChangedFcn = createCallbackFcn(app, @T2S2ValueChanged, true);
            app.T2S3 = uislider(app.Tab2);
            app.T2S3.Limits = [0.7 2];
            app.T2S3.ValueChangedFcn = createCallbackFcn(app, @T2S3ValueChanged, true);
            app.T2S3.Value = 1;
            app.T2B3 = uibutton(app.Tab2, 'push');
            app.T2B3.ButtonPushedFcn = createCallbackFcn(app, @T2B3ButtonPushed, true);
            app.T2B3.Text = 'Reset zoom';
            app.SlicedirectionDropDownLabel_2 = uilabel(app.Tab2);
            app.SlicedirectionDropDownLabel_2.Text = 'Slice direction';
            app.T2DD1 = uidropdown(app.Tab2);
            app.T2DD1.Items = {'x-axis', 'y-axis', 'z-axis'};
            app.T2DD1.ValueChangedFcn = createCallbackFcn(app, @T2DD1ValueChanged, true);
            app.T2DD1.Value = 'x-axis';
            app.RotationaxisLabel_2 = uilabel(app.Tab2);
            app.RotationaxisLabel_2.Text = 'Rotation axis';
            app.T2DD2 = uidropdown(app.Tab2);
            app.T2DD2.Items = {'x-axis', 'y-axis', 'z-axis'};
            app.T2DD2.Value = 'z-axis';
            app.RotationangleLabel_2 = uilabel(app.Tab2);
            app.RotationangleLabel_2.Text = 'Rotation angle';
            app.T2DD3 = uidropdown(app.Tab2);
            app.T2DD3.Items = {'Azimuth', 'Elevation', 'Azimuth_Reverse', 'Elevation_Reverse', ''};
            app.T2DD3.Value = 'Azimuth';
            app.T2B0 = uibutton(app.Tab2, 'push');
            app.T2B0.ButtonPushedFcn = createCallbackFcn(app, @T2B0ButtonPushed, true);
            app.T2B0.Text = 'Initialize this tab';
            app.Label_3 = uilabel(app.Tab2);
            app.Label_3.Text = '(1)';
            app.ParametersettingFirststepPanel = uipanel(app.Tab2);
            app.ParametersettingFirststepPanel.Title = 'Parameter setting (First step)';
            app.MinClusterSizenumberofatomsLabel_2 = uilabel(app.ParametersettingFirststepPanel);
            app.MinClusterSizenumberofatomsLabel_2.Text = 'MinClusterSize (number of atoms)';
            app.T2EFT01 = uieditfield(app.ParametersettingFirststepPanel, 'text');
            app.MinSamplesnumberofatomsLabel = uilabel(app.ParametersettingFirststepPanel);
            app.MinSamplesnumberofatomsLabel.Text = 'MinSamples (number of atoms)';
            app.T2EFT02 = uieditfield(app.ParametersettingFirststepPanel, 'text');
            app.ProbabilitythresholdExample09Label_2 = uilabel(app.ParametersettingFirststepPanel);
            app.ProbabilitythresholdExample09Label_2.Text = 'Probability threshold (Example: 0.9)';
            app.T2EFT03 = uieditfield(app.ParametersettingFirststepPanel, 'text');
            app.T2B1 = uibutton(app.ParametersettingFirststepPanel, 'push');
            app.T2B1.ButtonPushedFcn = createCallbackFcn(app, @T2B1ButtonPushed, true);
            app.T2B1.Text = 'Find dense objects';
            app.Label_5 = uilabel(app.ParametersettingFirststepPanel);
            app.Label_5.Text = '(2)';
            app.T2P2 = uipanel(app.Tab2);
            app.T2P2.Title = 'Parameter setting (Second step)';
            app.T2P2.Visible = 'off';
            app.PersistencescorethresholdEditFieldLabel = uilabel(app.T2P2);
            app.PersistencescorethresholdEditFieldLabel.Text = 'Persistence score threshold';
            app.T2EFT2 = uieditfield(app.T2P2, 'text');
            app.T2CB1 = uicheckbox(app.T2P2);
            app.T2CB1.Text = 'Plot (considering persistence score)';
            app.T2CB2 = uicheckbox(app.T2P2);
            app.T2CB2.ValueChangedFcn = createCallbackFcn(app, @T2CB2ValueChanged, true);
            app.T2CB2.Text = 'All dense objects are detected';
            app.T2P2_2 = uipanel(app.Tab2);
            app.T2P2_2.Title = 'Exploring the detected dense objects';
            app.T2P2_2.Visible = 'off';
            app.T2B4 = uibutton(app.T2P2_2, 'push');
            app.T2B4.ButtonPushedFcn = createCallbackFcn(app, @T2B4ButtonPushed, true);
            app.T2B4.Text = {'Get dense object ID'; ''};
            app.DenseobjectIDExample137Label = uilabel(app.T2P2_2);
            app.DenseobjectIDExample137Label.Text = {'Dense object ID'; 'Example: 1,3,7'};
            app.T2EFT3 = uieditfield(app.T2P2_2, 'text');
            app.T2B5 = uibutton(app.T2P2_2, 'push');
            app.T2B5.ButtonPushedFcn = createCallbackFcn(app, @T2B5ButtonPushed, true);
            app.T2B5.Text = 'Plot selected objects';
            app.T2B6 = uibutton(app.T2P2_2, 'push');
            app.T2B6.ButtonPushedFcn = createCallbackFcn(app, @T2B6ButtonPushed, true);
            app.T2B6.Text = 'Plot NOT selected objects';
            app.T2P2_3 = uipanel(app.Tab2);
            app.T2P2_3.Title = 'Finalizing this tab';
            app.IDofdenseobjectstoremoveExample3921Label = uilabel(app.T2P2_3);
            app.IDofdenseobjectstoremoveExample3921Label.Text = {'ID of dense objects '; 'to remove'; 'Example: 3,9,21'};
            app.T2EFT4 = uieditfield(app.T2P2_3, 'text');
            app.T2B7 = uibutton(app.T2P2_3, 'push');
            app.T2B7.ButtonPushedFcn = createCallbackFcn(app, @T2B7ButtonPushed, true);
            app.T2B7.Text = 'Finalize the results of this tab';
            app.Label_4 = uilabel(app.T2P2_3);
            app.Label_4.Text = '(3)';
            app.Tab3 = uitab(app.TabGroup);
            app.Tab3.Title = '(2/2) Detecting dense objects (pruning)';
            app.T3F1 = uiaxes(app.Tab3);
            title(app.T3F1, 'Title')
            xlabel(app.T3F1, 'X')
            ylabel(app.T3F1, 'Y')
            app.T3F1.Visible = 'off';
            app.T3Lamp1 = uilamp(app.Tab3);
            app.T3L1 = uilabel(app.Tab3);
            app.T3L1.Text = {'Press the "Initialize this tab" button'; ''};
            app.T3EFN1 = uieditfield(app.Tab3, 'numeric');
            app.T3EFN1.Value = 8;
            app.T3L2 = uilabel(app.Tab3);
            app.T3L2.Text = 'Slice thickness (nm)';
            app.T3S1 = uislider(app.Tab3);
            app.T3S1.Limits = [0 1];
            app.T3S1.ValueChangedFcn = createCallbackFcn(app, @T3S1ValueChanged, true);
            app.T3S1.Value = 0.5;
            app.T3B3 = uibutton(app.Tab3, 'push');
            app.T3B3.ButtonPushedFcn = createCallbackFcn(app, @T3B3ButtonPushed, true);
            app.T3B3.Text = 'Reset';
            app.T3S2 = uislider(app.Tab3);
            app.T3S2.Limits = [0 360];
            app.T3S2.ValueChangedFcn = createCallbackFcn(app, @T3S2ValueChanged, true);
            app.T3S3 = uislider(app.Tab3);
            app.T3S3.Limits = [0.7 2];
            app.T3S3.ValueChangedFcn = createCallbackFcn(app, @T3S3ValueChanged, true);
            app.T3S3.Value = 1;
            app.T3B4 = uibutton(app.Tab3, 'push');
            app.T3B4.ButtonPushedFcn = createCallbackFcn(app, @T3B4ButtonPushed, true);
            app.T3B4.Text = 'Reset zoom';
            app.SlicedirectionDropDownLabel_3 = uilabel(app.Tab3);
            app.SlicedirectionDropDownLabel_3.Text = 'Slice direction';
            app.T3DD1 = uidropdown(app.Tab3);
            app.T3DD1.Items = {'x-axis', 'y-axis', 'z-axis'};
            app.T3DD1.ValueChangedFcn = createCallbackFcn(app, @T3DD1ValueChanged, true);
            app.T3DD1.Value = 'x-axis';
            app.RotationaxisLabel_3 = uilabel(app.Tab3);
            app.RotationaxisLabel_3.Text = 'Rotation axis';
            app.T3DD2 = uidropdown(app.Tab3);
            app.T3DD2.Items = {'x-axis', 'y-axis', 'z-axis'};
            app.T3DD2.Value = 'z-axis';
            app.RotationangleLabel_3 = uilabel(app.Tab3);
            app.RotationangleLabel_3.Text = 'Rotation angle';
            app.T3DD3 = uidropdown(app.Tab3);
            app.T3DD3.Items = {'Azimuth', 'Elevation', 'Azimuth_Reverse', 'Elevation_Reverse', ''};
            app.T3DD3.Value = 'Azimuth';
            app.T3B2 = uibutton(app.Tab3, 'push');
            app.T3B2.ButtonPushedFcn = createCallbackFcn(app, @T3B2ButtonPushed, true);
            app.T3B2.Text = {'Get dense object ID'; ''};
            app.T3F2 = uiaxes(app.Tab3);
            title(app.T3F2, 'Title')
            xlabel(app.T3F2, 'X')
            ylabel(app.T3F2, 'Y')
            app.T3F2.Visible = 'off';
            app.T3F3 = uiaxes(app.Tab3);
            title(app.T3F3, 'Title')
            xlabel(app.T3F3, 'X')
            ylabel(app.T3F3, 'Y')
            app.T3F3.Visible = 'off';
            app.T3P3 = uipanel(app.Tab3);
            app.T3P3.Title = 'Pruning (breaking) merged objects';
            app.T3B7 = uibutton(app.T3P3, 'push');
            app.T3B7.ButtonPushedFcn = createCallbackFcn(app, @T3B7ButtonPushed, true);
            app.T3B7.Text = 'Plot';
            app.T3P2 = uipanel(app.Tab3);
            app.T3P2.Title = 'Stitching dense objects';
            app.ObjectIDExample1273564Label = uilabel(app.T3P2);
            app.ObjectIDExample1273564Label.Text = {'Object ID'; '(Example:1,2;7,3,5;6,4)'};
            app.T3EFT3 = uieditfield(app.T3P2, 'text');
            app.T3B6 = uibutton(app.T3P2, 'push');
            app.T3B6.ButtonPushedFcn = createCallbackFcn(app, @T3B6ButtonPushed, true);
            app.T3B6.Text = 'Plot';
            app.T3P1 = uipanel(app.Tab3);
            app.T3P1.Title = 'Increasing the probability threshold';
            app.T3B5 = uibutton(app.T3P1, 'push');
            app.T3B5.ButtonPushedFcn = createCallbackFcn(app, @T3B5ButtonPushed, true);
            app.T3B5.Text = 'Plot';
            app.ObjectIDExample12115Label_2 = uilabel(app.Tab3);
            app.ObjectIDExample12115Label_2.Text = {'Object ID'; '(Example:1,21,15)'};
            app.T3EFT1 = uieditfield(app.Tab3, 'text');
            app.ProbabilitythresholdEditFieldLabel_4 = uilabel(app.Tab3);
            app.ProbabilitythresholdEditFieldLabel_4.Text = 'Probability threshold';
            app.T3EFT2 = uieditfield(app.Tab3, 'text');
            app.T3P4 = uipanel(app.Tab3);
            app.T3P4.Title = 'Applying corrections';
            app.T3B8 = uibutton(app.T3P4, 'push');
            app.T3B8.ButtonPushedFcn = createCallbackFcn(app, @T3B8ButtonPushed, true);
            app.T3B8.Text = 'Finalizing the results';
            app.ObjectIDandprobabilitythresholdExample1009570979094Label = uilabel(app.T3P4);
            app.ObjectIDandprobabilitythresholdExample1009570979094Label.Text = {'Object ID and probability threshold'; '(Example:10,0.95;7,0.97;9,0.94)'};
            app.T3EFT6 = uieditfield(app.T3P4, 'text');
            app.ObjectIDsforstitchingExample1273564Label = uilabel(app.T3P4);
            app.ObjectIDsforstitchingExample1273564Label.Text = {'Object IDs for stitching'; '(Example:1,2;7,3,5;6,4)'};
            app.T3EFT7 = uieditfield(app.T3P4, 'text');
            app.InformationforpruningExample122015097251108513310094Label = uilabel(app.T3P4);
            app.InformationforpruningExample122015097251108513310094Label.Text = {'Information for pruning'; ''; 'Example: '; '(12,20,15,0.9;7,25,11,0.85;1,33,10,0.94)'};
            app.T3EFT8 = uieditfield(app.T3P4, 'text');
            app.Label_7 = uilabel(app.T3P4);
            app.Label_7.Text = '(2)';
            app.T3CB1 = uicheckbox(app.T3P4);
            app.T3CB1.ValueChangedFcn = createCallbackFcn(app, @T3CB1ValueChanged, true);
            app.T3CB1.Text = 'Show Help';
            app.T3CB2 = uicheckbox(app.T3P4);
            app.T3CB2.Text = {'Write "Others" atoms in the pos file '; '(atoms with mass-to-charge ratio values other '; 'than those set in Tab 1)'};
            app.T3B1 = uibutton(app.Tab3, 'push');
            app.T3B1.ButtonPushedFcn = createCallbackFcn(app, @T3B1ButtonPushed, true);
            app.T3B1.Text = 'Initialize this tab';
            app.Label_6 = uilabel(app.Tab3);
            app.Label_6.Text = '(1)';
            app.StaticMessage_4 = uilabel(app.Tab3);
            app.StaticMessage_4.Text = '(ObjectID,MinClusterSize,MinSamples,ProbabilityThreshold)';
            app.Label_8 = uilabel(app.Tab3);
            app.Label_8.Text = {'The above three panels are only for "try and error"'; 'and their results will not be saved.'; 'After finding the most appropiate values for each panel, '; 'please write them in the same color box in the "apply corrections" panel.'};
            app.T3Lh1 = uilabel(app.Tab3);
            app.T3Lh1.Visible = 'off';
            app.T3Lh1.Text = {'If we want to increase the probability threshold for '; 'objects with ID values of 1,3 and 4 to 0.95 and for objects with ID values of 8 and 10 to 0.97, '; 'we must write the ID of each object and then '','' and then the desired probability threshold value  and finally '';''. '; 'For the mentioned values it would be: 1,0.95;3,0.95;4,0.95;8,0.97;10,0.97'};
            app.T3Lh2 = uilabel(app.Tab3);
            app.T3Lh2.Visible = 'off';
            app.T3Lh2.Text = {'If we want to stitch dense objects with ID values of 1 and 2 together and '; 'dense objects with ID values of 7, 3 and 5 together and '; 'also dense objects with ID values of 6 and 4 together, we must write them as follows:'; '1,2;7,3,5;6,4'};
            app.T3Lh3 = uilabel(app.Tab3);
            app.T3Lh3.Visible = 'off';
            app.T3Lh3.Text = {'If we want to prune dense objects with ID values of 11, 12 and 13 '; 'using the MinClusterSize, MinSampleSize and probability threshold values of 50, 20 and 0.9, respectively, '; 'we must first write the ObjectID '','' MinClusterSize '','' MinSamples '','' ProbabilityThreshold '';'''; 'For instance for the mentioned values '; '11,50,20,0.9;12,50,20,0.9;13,50,20,0.9'; ''};
            app.T3F4 = uiaxes(app.Tab3);
            title(app.T3F4, '')
            xlabel(app.T3F4, '')
            ylabel(app.T3F4, '')
            app.T3F4.YTick = [0 0.5 1];
            app.T3F4.Visible = 'off';
            app.ObjectIDExample1Label = uilabel(app.Tab3);
            app.ObjectIDExample1Label.Text = 'Object ID (Example: 1)';
            app.T3EFN3 = uieditfield(app.Tab3, 'text');
            app.MinClusterSizeeg25Label = uilabel(app.Tab3);
            app.MinClusterSizeeg25Label.Text = 'MinClusterSize (e.g., 25)';
            app.T3EFN4 = uieditfield(app.Tab3, 'text');
            app.MinSampleseg15Label = uilabel(app.Tab3);
            app.MinSampleseg15Label.Text = 'MinSamples (e.g., 15)';
            app.T3EFN5 = uieditfield(app.Tab3, 'text');
            app.Probabilitythresholdeg09Label = uilabel(app.Tab3);
            app.Probabilitythresholdeg09Label.Text = {'Probability threshold'; '(e.g., 0.9)'};
            app.T3EFT5 = uieditfield(app.Tab3, 'text');
            app.Tab4 = uitab(app.TabGroup);
            app.Tab4.Title = '(3) Extracting skeletons';
            app.T4F11 = uiaxes(app.Tab4);
            title(app.T4F11, '')
            xlabel(app.T4F11, '')
            ylabel(app.T4F11, '')
            app.T4F11.Visible = 'off';
            app.T4F4 = uiaxes(app.Tab4);
            title(app.T4F4, 'Title')
            xlabel(app.T4F4, 'X')
            ylabel(app.T4F4, 'Y')
            app.T4F4.Visible = 'off';
            app.T4F1 = uiaxes(app.Tab4);
            title(app.T4F1, 'Title')
            xlabel(app.T4F1, 'X')
            ylabel(app.T4F1, 'Y')
            app.T4F1.Visible = 'off';
            app.T4F2 = uiaxes(app.Tab4);
            title(app.T4F2, 'Title')
            xlabel(app.T4F2, 'X')
            ylabel(app.T4F2, 'Y')
            app.T4F2.Visible = 'off';
            app.T4F3 = uiaxes(app.Tab4);
            title(app.T4F3, 'Title')
            xlabel(app.T4F3, 'X')
            ylabel(app.T4F3, 'Y')
            app.T4F3.Visible = 'off';
            app.T4F5 = uiaxes(app.Tab4);
            title(app.T4F5, 'Title')
            xlabel(app.T4F5, 'X')
            ylabel(app.T4F5, 'Y')
            app.T4F5.Visible = 'off';
            app.T4Lamp1 = uilamp(app.Tab4);
            app.T4L1 = uilabel(app.Tab4);
            app.T4L1.Text = {'Press the "Initialize this tab" button'; ''};
            app.T4F6 = uiaxes(app.Tab4);
            title(app.T4F6, 'Title')
            xlabel(app.T4F6, 'X')
            ylabel(app.T4F6, 'Y')
            app.T4F6.Visible = 'off';
            app.T4F10 = uiaxes(app.Tab4);
            title(app.T4F10, 'Title')
            xlabel(app.T4F10, 'X')
            ylabel(app.T4F10, 'Y')
            app.T4F10.Visible = 'off';
            app.T4F9 = uiaxes(app.Tab4);
            title(app.T4F9, 'Title')
            xlabel(app.T4F9, 'X')
            ylabel(app.T4F9, 'Y')
            app.T4F9.Visible = 'off';
            app.T4F8 = uiaxes(app.Tab4);
            title(app.T4F8, 'Title')
            xlabel(app.T4F8, 'X')
            ylabel(app.T4F8, 'Y')
            app.T4F8.Visible = 'off';
            app.T4F7 = uiaxes(app.Tab4);
            title(app.T4F7, 'Title')
            xlabel(app.T4F7, 'X')
            ylabel(app.T4F7, 'Y')
            app.T4F7.Visible = 'off';
            app.T4F12 = uiaxes(app.Tab4);
            title(app.T4F12, 'Title')
            xlabel(app.T4F12, 'X')
            ylabel(app.T4F12, 'Y')
            app.T4F12.Visible = 'off';
            app.T4F13 = uiaxes(app.Tab4);
            title(app.T4F13, 'Title')
            xlabel(app.T4F13, 'X')
            ylabel(app.T4F13, 'Y')
            app.T4F13.Visible = 'off';
            app.T4EFN1 = uieditfield(app.Tab4, 'numeric');
            app.T4EFN1.Value = 8;
            app.T2L2_2 = uilabel(app.Tab4);
            app.T2L2_2.Text = 'Slice thickness (nm)';
            app.T4S1 = uislider(app.Tab4);
            app.T4S1.Limits = [0 1];
            app.T4S1.ValueChangedFcn = createCallbackFcn(app, @T4S1ValueChanged, true);
            app.T4S1.Value = 0.5;
            app.T4B3 = uibutton(app.Tab4, 'push');
            app.T4B3.ButtonPushedFcn = createCallbackFcn(app, @T4B3ButtonPushed, true);
            app.T4B3.Text = 'Reset';
            app.SlicedirectionDropDownLabel_4 = uilabel(app.Tab4);
            app.SlicedirectionDropDownLabel_4.Text = 'Slice direction';
            app.T4DD1 = uidropdown(app.Tab4);
            app.T4DD1.Items = {'x-axis', 'y-axis', 'z-axis'};
            app.T4DD1.ValueChangedFcn = createCallbackFcn(app, @T4DD1ValueChanged, true);
            app.T4DD1.Value = 'x-axis';
            app.T4S2 = uislider(app.Tab4);
            app.T4S2.Limits = [0 360];
            app.T4S2.ValueChangedFcn = createCallbackFcn(app, @T4S2ValueChanged, true);
            app.RotationaxisLabel_4 = uilabel(app.Tab4);
            app.RotationaxisLabel_4.Text = 'Rotation axis';
            app.T4DD2 = uidropdown(app.Tab4);
            app.T4DD2.Items = {'x-axis', 'y-axis', 'z-axis'};
            app.T4DD2.Value = 'z-axis';
            app.RotationangleLabel_4 = uilabel(app.Tab4);
            app.RotationangleLabel_4.Text = 'Rotation angle';
            app.T4DD3 = uidropdown(app.Tab4);
            app.T4DD3.Items = {'Azimuth', 'Elevation', 'Azimuth_Reverse', 'Elevation_Reverse', ''};
            app.T4DD3.Value = 'Azimuth';
            app.T4S3 = uislider(app.Tab4);
            app.T4S3.Limits = [0.7 2];
            app.T4S3.ValueChangedFcn = createCallbackFcn(app, @T4S3ValueChanged, true);
            app.T4S3.Value = 1;
            app.T4B4 = uibutton(app.Tab4, 'push');
            app.T4B4.ButtonPushedFcn = createCallbackFcn(app, @T4B4ButtonPushed, true);
            app.T4B4.Text = 'Reset zoom';
            app.T3P4_2 = uipanel(app.Tab4);
            app.T3P4_2.Title = 'Applying corrections';
            app.T4B6 = uibutton(app.T3P4_2, 'push');
            app.T4B6.ButtonPushedFcn = createCallbackFcn(app, @T4B6ButtonPushed, true);
            app.T4B6.Text = 'Finalizing the results';
            app.T4B5 = uibutton(app.T3P4_2, 'push');
            app.T4B5.ButtonPushedFcn = createCallbackFcn(app, @T4B5ButtonPushed, true);
            app.T4B5.Text = {'Get skeleton ID'; ''};
            app.Label_11 = uilabel(app.T3P4_2);
            app.Label_11.Text = '(3)';
            app.T4P1 = uipanel(app.Tab4);
            app.T4P1.Title = 'Doing analysis for all the objects';
            app.T4B1 = uibutton(app.T4P1, 'push');
            app.T4B1.ButtonPushedFcn = createCallbackFcn(app, @T4B1ButtonPushed, true);
            app.T4B1.Text = 'Initialize this tab';
            app.T4T1 = uitable(app.T4P1);
            app.T4T1.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.T4T1.RowName = {};
            app.T4B2 = uibutton(app.T4P1, 'push');
            app.T4B2.ButtonPushedFcn = createCallbackFcn(app, @T4B2ButtonPushed, true);
            app.T4B2.Text = 'Extract skeletons';
            app.T4CB0 = uicheckbox(app.T4P1);
            app.T4CB0.ValueChangedFcn = createCallbackFcn(app, @T4CB0ValueChanged, true);
            app.T4CB0.Text = 'Show Help';
            app.Label_9 = uilabel(app.T4P1);
            app.Label_9.Text = '(1)';
            app.Label_10 = uilabel(app.T4P1);
            app.Label_10.Text = '(2)';
            app.T4CB2 = uicheckbox(app.T4P1);
            app.T4CB2.ValueChangedFcn = createCallbackFcn(app, @T4CB2ValueChanged, true);
            app.T4CB2.Text = 'Show plot number';
            app.Label_T4F4 = uilabel(app.Tab4);
            app.Label_T4F4.Visible = 'off';
            app.Label_T4F4.Text = {'Original '; 'points'};
            app.Label_T4F4_2 = uilabel(app.Tab4);
            app.Label_T4F4_2.Visible = 'off';
            app.Label_T4F4_2.Text = {'Contracted '; 'points'};
            app.Label_T4F6_1 = uilabel(app.Tab4);
            app.Label_T4F6_1.Visible = 'off';
            app.Label_T4F6_1.Text = {'Further down sampling mesh '; 'Laplacian contracted point cloud'};
            app.Label_T4F6_2 = uilabel(app.Tab4);
            app.Label_T4F6_2.Visible = 'off';
            app.Label_T4F6_2.Text = {'Mesh Laplacian '; 'contracted points '};
            app.Label_T4F6_3 = uilabel(app.Tab4);
            app.Label_T4F6_3.Visible = 'off';
            app.Label_T4F6_3.Text = {'New contraction '; 'points'};
            app.T4CB1 = uicheckbox(app.Tab4);
            app.T4CB1.ValueChangedFcn = createCallbackFcn(app, @T4CB1ValueChanged, true);
            app.T4CB1.Text = 'Puase at the end of this iteration';
            app.FigurestorotateLabel = uilabel(app.Tab4);
            app.FigurestorotateLabel.Text = 'Figures to rotate';
            app.T4DD4 = uidropdown(app.Tab4);
            app.T4DD4.Items = {'All (Plots 11-13)', 'Skeleton (Plots 1-10)', 'Selected (Plot 14)'};
            app.T4DD4.Value = 'All (Plots 11-13)';
            app.T4F14 = uiaxes(app.Tab4);
            title(app.T4F14, 'Title')
            xlabel(app.T4F14, 'X')
            ylabel(app.T4F14, 'Y')
            app.T4F14.Visible = 'off';
            app.T4T2 = uitable(app.Tab4);
            app.T4T2.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.T4T2.RowName = {};
            app.T4L2 = uilabel(app.Tab4);
            app.T4L2.Visible = 'off';
            app.T4L2.Text = 'Plot #1';
            app.T4L3 = uilabel(app.Tab4);
            app.T4L3.Visible = 'off';
            app.T4L3.Text = 'Plot #2';
            app.T4L4 = uilabel(app.Tab4);
            app.T4L4.Visible = 'off';
            app.T4L4.Text = 'Plot #3';
            app.T4L5 = uilabel(app.Tab4);
            app.T4L5.Visible = 'off';
            app.T4L5.Text = 'Plot #4';
            app.T4L6 = uilabel(app.Tab4);
            app.T4L6.Visible = 'off';
            app.T4L6.Text = 'Plot #5';
            app.T4L7 = uilabel(app.Tab4);
            app.T4L7.Visible = 'off';
            app.T4L7.Text = 'Plot #6';
            app.T4L8 = uilabel(app.Tab4);
            app.T4L8.Visible = 'off';
            app.T4L8.Text = 'Plot #7';
            app.T4L9 = uilabel(app.Tab4);
            app.T4L9.Visible = 'off';
            app.T4L9.Text = 'Plot #8';
            app.T4L10 = uilabel(app.Tab4);
            app.T4L10.Visible = 'off';
            app.T4L10.Text = 'Plot #9';
            app.T4L11 = uilabel(app.Tab4);
            app.T4L11.Visible = 'off';
            app.T4L11.Text = 'Plot #10';
            app.T4L12 = uitextarea(app.Tab4);
            app.T4L12.Visible = 'off';
            app.T4L13 = uilabel(app.Tab4);
            app.T4L13.Visible = 'off';
            app.T4L13.Text = 'Plot #11';
            app.T4L14 = uilabel(app.Tab4);
            app.T4L14.Visible = 'off';
            app.T4L14.Text = 'Plot #12';
            app.T4L15 = uilabel(app.Tab4);
            app.T4L15.Visible = 'off';
            app.T4L15.Text = 'Plot #13';
            app.T4L16 = uilabel(app.Tab4);
            app.T4L16.Visible = 'off';
            app.T4L16.Text = 'Plot #14';
            app.T4L18 = uilabel(app.Tab4);
            app.T4L18.Visible = 'off';
            app.T4L18.Text = 'Now you can rotate the above plots';
            app.Tab5 = uitab(app.TabGroup);
            app.Tab5.Title = '(4/1) Postprocessing (final corrections)';
            app.T5F1 = uiaxes(app.Tab5);
            title(app.T5F1, '')
            xlabel(app.T5F1, '')
            ylabel(app.T5F1, '')
            app.T5F1.Visible = 'off';
            app.T5F2 = uiaxes(app.Tab5);
            title(app.T5F2, '')
            xlabel(app.T5F2, '')
            ylabel(app.T5F2, '')
            app.T5F2.Visible = 'off';
            app.T5F3 = uiaxes(app.Tab5);
            title(app.T5F3, '')
            xlabel(app.T5F3, '')
            ylabel(app.T5F3, '')
            app.T5F3.Visible = 'off';
            app.T5F4 = uiaxes(app.Tab5);
            title(app.T5F4, '')
            xlabel(app.T5F4, '')
            ylabel(app.T5F4, '')
            app.T5F4.Visible = 'off';
            app.T5F5 = uiaxes(app.Tab5);
            title(app.T5F5, '')
            xlabel(app.T5F5, '')
            ylabel(app.T5F5, '')
            app.T5F5.Visible = 'off';
            app.T5F6 = uiaxes(app.Tab5);
            title(app.T5F6, '')
            xlabel(app.T5F6, '')
            ylabel(app.T5F6, '')
            app.T5F6.Visible = 'off';
            app.T5EFN11 = uieditfield(app.Tab5, 'numeric');
            app.T5EFN11.Value = 8;
            app.T2L2_3 = uilabel(app.Tab5);
            app.T2L2_3.Text = 'Slice thickness (nm)';
            app.T5S1 = uislider(app.Tab5);
            app.T5S1.Limits = [0 1];
            app.T5S1.ValueChangedFcn = createCallbackFcn(app, @T5S1ValueChanged, true);
            app.T5S1.Value = 0.5;
            app.T5B9 = uibutton(app.Tab5, 'push');
            app.T5B9.ButtonPushedFcn = createCallbackFcn(app, @T5B9ButtonPushed, true);
            app.T5B9.Text = 'Reset';
            app.SlicedirectionDropDownLabel_5 = uilabel(app.Tab5);
            app.SlicedirectionDropDownLabel_5.Text = 'Slice direction';
            app.T5DD1 = uidropdown(app.Tab5);
            app.T5DD1.Items = {'x-axis', 'y-axis', 'z-axis'};
            app.T5DD1.ValueChangedFcn = createCallbackFcn(app, @T5DD1ValueChanged, true);
            app.T5DD1.Value = 'x-axis';
            app.T5S2 = uislider(app.Tab5);
            app.T5S2.Limits = [0 360];
            app.T5S2.ValueChangedFcn = createCallbackFcn(app, @T5S2ValueChanged, true);
            app.RotationaxisLabel_6 = uilabel(app.Tab5);
            app.RotationaxisLabel_6.Text = 'Rotation axis';
            app.T5DD2 = uidropdown(app.Tab5);
            app.T5DD2.Items = {'x-axis', 'y-axis', 'z-axis'};
            app.T5DD2.Value = 'z-axis';
            app.RotationangleLabel_5 = uilabel(app.Tab5);
            app.RotationangleLabel_5.Text = 'Rotation angle';
            app.T5DD3 = uidropdown(app.Tab5);
            app.T5DD3.Items = {'Azimuth', 'Elevation', 'Azimuth_Reverse', 'Elevation_Reverse', ''};
            app.T5DD3.Value = 'Azimuth';
            app.T5S3 = uislider(app.Tab5);
            app.T5S3.Limits = [0.7 2];
            app.T5S3.ValueChangedFcn = createCallbackFcn(app, @T5S3ValueChanged, true);
            app.T5S3.Value = 1;
            app.T5B10 = uibutton(app.Tab5, 'push');
            app.T5B10.ButtonPushedFcn = createCallbackFcn(app, @T5B10ButtonPushed, true);
            app.T5B10.Text = 'Reset zoom';
            app.T5Lamp1 = uilamp(app.Tab5);
            app.T5L1 = uilabel(app.Tab5);
            app.T5L1.Text = {'Press the "Initialize this tab" button'; ''};
            app.T5P1 = uipanel(app.Tab5);
            app.T5P1.Title = 'Exploring the results of previous tabs';
            app.T5B1 = uibutton(app.T5P1, 'push');
            app.T5B1.ButtonPushedFcn = createCallbackFcn(app, @T5B1ButtonPushed, true);
            app.T5B1.Text = 'Initialize this tab';
            app.SkeletonlengththresholdnmLabel = uilabel(app.T5P1);
            app.SkeletonlengththresholdnmLabel.Text = 'Skeleton length threshold (nm)';
            app.T5EFT1 = uieditfield(app.T5P1, 'text');
            app.T5EFT1.Value = '15';
            app.T5B2 = uibutton(app.T5P1, 'push');
            app.T5B2.ButtonPushedFcn = createCallbackFcn(app, @T5B2ButtonPushed, true);
            app.T5B2.Text = 'Plot';
            app.T5EFT2Label = uilabel(app.T5P1);
            app.T5EFT2Label.Visible = 'off';
            app.T5EFT2Label.Text = {'Skeleton IDs '; '(Example: 1,12,3)'};
            app.T5EFT2 = uieditfield(app.T5P1, 'text');
            app.T5EFT2.Visible = 'off';
            app.T5B3 = uibutton(app.T5P1, 'push');
            app.T5B3.ButtonPushedFcn = createCallbackFcn(app, @T5B3ButtonPushed, true);
            app.T5B3.Visible = 'off';
            app.T5B3.Text = {'Plot selected '; 'skeletons'};
            app.Label_13 = uilabel(app.T5P1);
            app.Label_13.Text = '(2)';
            app.T5F7 = uiaxes(app.Tab5);
            title(app.T5F7, '')
            xlabel(app.T5F7, '')
            ylabel(app.T5F7, '')
            app.T5F7.Visible = 'off';
            app.T5T1 = uitable(app.Tab5);
            app.T5T1.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.T5T1.RowName = {};
            app.T5T1.Visible = 'off';
            app.T5F8 = uiaxes(app.Tab5);
            title(app.T5F8, '')
            xlabel(app.T5F8, '')
            ylabel(app.T5F8, '')
            app.T5F8.Visible = 'off';
            app.T5P5 = uipanel(app.Tab5);
            app.T5P5.Title = 'Finding the crystallographic plane of a dislocation loop';
            app.T5B8 = uibutton(app.T5P5, 'push');
            app.T5B8.ButtonPushedFcn = createCallbackFcn(app, @T5B8ButtonPushed, true);
            app.T5B8.Text = 'Crystallographic plane';
            app.T5L12 = uilabel(app.T5P5);
            app.T5L12.Visible = 'off';
            app.T5L12.Text = 'Plot #9';
            app.EBSDEuleranglesdegreeExample1515121Label = uilabel(app.T5P5);
            app.EBSDEuleranglesdegreeExample1515121Label.Text = {'EBSD Euler angles (degree)'; '(Example: 15,15,121)'};
            app.T5EFT11 = uieditfield(app.T5P5, 'text');
            app.DislocationloopIDExample12Label = uilabel(app.T5P5);
            app.DislocationloopIDExample12Label.Text = 'Dislocation loop ID (Example: 12)';
            app.T5EFT12 = uieditfield(app.T5P5, 'text');
            app.LEAPEBSDrotationangledegreeLabel = uilabel(app.T5P5);
            app.LEAPEBSDrotationangledegreeLabel.Text = 'LEAP-EBSD rotation angle (degree)';
            app.T5EFT13 = uieditfield(app.T5P5, 'text');
            app.T5EFT13.Value = '103';
            app.T5RB1 = uibuttongroup(app.T5P5);
            app.T5RB1.Title = 'Point group';
            app.T5RB1cubic = uiradiobutton(app.T5RB1);
            app.T5RB1cubic.Text = 'cubic (m-3m)';
            app.T5RB1hcp = uiradiobutton(app.T5RB1);
            app.T5RB1hcp.Text = 'hcp (6/mmm)';
            app.T5RB1Hidden = uiradiobutton(app.T5RB1);
            app.T5RB1Hidden.Visible = 'off';
            app.T5RB1Hidden.Text = 'Hidden';
            app.T5RB1Hidden.Value = true;
            app.h_maxEditFieldLabel = uilabel(app.T5P5);
            app.h_maxEditFieldLabel.Text = 'h_max';
            app.T5EF14 = uieditfield(app.T5P5, 'numeric');
            app.T5EF14.Value = 10;
            app.k_maxLabel = uilabel(app.T5P5);
            app.k_maxLabel.Text = 'k_max';
            app.T5EF15 = uieditfield(app.T5P5, 'numeric');
            app.T5EF15.Value = 10;
            app.l_maxLabel = uilabel(app.T5P5);
            app.l_maxLabel.Text = 'l_max';
            app.T5EF16 = uieditfield(app.T5P5, 'numeric');
            app.T5EF16.Value = 10;
            app.StepsizeLabel = uilabel(app.T5P5);
            app.StepsizeLabel.Text = 'Step size';
            app.T5EF17 = uieditfield(app.T5P5, 'numeric');
            app.T5EF17.Value = 1;
            app.T5T2 = uitable(app.T5P5);
            app.T5T2.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.T5T2.RowName = {};
            app.T5T2.Visible = 'off';
            app.T5F11 = uiaxes(app.T5P5);
            title(app.T5F11, '')
            xlabel(app.T5F11, '')
            ylabel(app.T5F11, '')
            app.T5F11.Visible = 'off';
            app.IPFplotforsolutionLabel = uilabel(app.T5P5);
            app.IPFplotforsolutionLabel.Text = {'IPF plot for '; 'solution #'};
            app.T5EF18 = uieditfield(app.T5P5, 'numeric');
            app.T5EF18.Value = 1;
            app.T5P3 = uipanel(app.Tab5);
            app.T5P3.Title = 'Tools';
            app.T5B4 = uibutton(app.T5P3, 'push');
            app.T5B4.ButtonPushedFcn = createCallbackFcn(app, @T5B4ButtonPushed, true);
            app.T5B4.Text = 'Get skeleton ID';
            app.T5B5 = uibutton(app.T5P3, 'push');
            app.T5B5.ButtonPushedFcn = createCallbackFcn(app, @T5B5ButtonPushed, true);
            app.T5B5.Text = 'Save the plot';
            app.T5CB1 = uicheckbox(app.T5P3);
            app.T5CB1.ValueChangedFcn = createCallbackFcn(app, @T5CB1ValueChanged, true);
            app.T5CB1.Text = 'Show plot number';
            app.PlotExample13Label = uilabel(app.T5P3);
            app.PlotExample13Label.Text = {'Plot # '; '(Example: 1,3)'};
            app.T5EFT0 = uieditfield(app.T5P3, 'text');
            app.T5P4 = uipanel(app.Tab5);
            app.T5P4.Title = 'Plotting solute variation from a dislocation skeleton or a cluster center';
            app.T5B7 = uibutton(app.T5P4, 'push');
            app.T5B7.ButtonPushedFcn = createCallbackFcn(app, @T5B7ButtonPushed, true);
            app.T5B7.Visible = 'off';
            app.T5B7.Text = 'Plot';
            app.T5F9 = uiaxes(app.T5P4);
            title(app.T5F9, '')
            xlabel(app.T5F9, '')
            ylabel(app.T5F9, '')
            app.T5F9.Visible = 'off';
            app.T5F10 = uiaxes(app.T5P4);
            title(app.T5F10, '')
            xlabel(app.T5F10, '')
            ylabel(app.T5F10, '')
            app.T5F10.Visible = 'off';
            app.T5L10 = uilabel(app.T5P4);
            app.T5L10.Visible = 'off';
            app.T5L10.Text = 'Plot #7';
            app.T5L11 = uilabel(app.T5P4);
            app.T5L11.Visible = 'off';
            app.T5L11.Text = 'Plot #8';
            app.SkeletonIDExample193orallDislocationsorallClustersLabel = uilabel(app.T5P4);
            app.SkeletonIDExample193orallDislocationsorallClustersLabel.Text = {'Skeleton ID'; 'Example: 1,9,3 or'; 'allDislocations or allClusters'};
            app.T5EFT10 = uieditfield(app.T5P4, 'text');
            app.T5EFT10.Value = '1,10,40';
            app.MindistancenmEditFieldLabel = uilabel(app.T5P4);
            app.MindistancenmEditFieldLabel.Text = 'Min. distance (nm)';
            app.T5EFT6 = uieditfield(app.T5P4, 'text');
            app.T5EFT6.Value = '0.5';
            app.MaxdistancenmLabel = uilabel(app.T5P4);
            app.MaxdistancenmLabel.Text = 'Max. distance (nm)';
            app.T5EFT7 = uieditfield(app.T5P4, 'text');
            app.T5EFT7.Value = '7';
            app.StepsizenmLabel = uilabel(app.T5P4);
            app.StepsizenmLabel.Text = 'Step size (nm)';
            app.T5EFT8 = uieditfield(app.T5P4, 'text');
            app.T5EFT8.Value = '0.5';
            app.T5L2 = uilabel(app.Tab5);
            app.T5L2.Visible = 'off';
            app.T5L2.Text = 'Plot #1';
            app.T5L3 = uilabel(app.Tab5);
            app.T5L3.Visible = 'off';
            app.T5L3.Text = 'Plot #2';
            app.T5L4 = uilabel(app.Tab5);
            app.T5L4.Visible = 'off';
            app.T5L4.Text = 'Plot #3';
            app.T5L5 = uilabel(app.Tab5);
            app.T5L5.Visible = 'off';
            app.T5L5.Text = 'Plot #4';
            app.T5L6 = uilabel(app.Tab5);
            app.T5L6.Visible = 'off';
            app.T5L6.Text = 'Plot #5';
            app.T5L7 = uilabel(app.Tab5);
            app.T5L7.Visible = 'off';
            app.T5L7.Text = 'Plot #6';
            app.T5L8 = uilabel(app.Tab5);
            app.T5L8.Visible = 'off';
            app.T5L8.Text = 'Plot #3';
            app.T5L9 = uilabel(app.Tab5);
            app.T5L9.Visible = 'off';
            app.T5L9.Text = 'Plot #4';
            app.Label_12 = uilabel(app.Tab5);
            app.Label_12.Text = '(1)';
            app.T5P2 = uipanel(app.Tab5);
            app.T5P2.Title = 'Applying corrections';
            app.IDofskeletonstoconsiderasdislocationsExample147EditFieldLabel = uilabel(app.T5P2);
            app.IDofskeletonstoconsiderasdislocationsExample147EditFieldLabel.Text = {'ID of skeletons to consider as dislocations'; '(Example: 1,4,7)'};
            app.T5EFT3 = uieditfield(app.T5P2, 'text');
            app.IDofskeletonstoconsiderasclustersExample258Label = uilabel(app.T5P2);
            app.IDofskeletonstoconsiderasclustersExample258Label.Text = {'ID of skeletons to consider as clusters'; '(Example: 2,5,8)'};
            app.T5EFT4 = uieditfield(app.T5P2, 'text');
            app.IDofskeletonstoconsiderasothersExample369Label = uilabel(app.T5P2);
            app.IDofskeletonstoconsiderasothersExample369Label.Text = {'ID of skeletons to consider as others'; '(Example: 3,6,9)'};
            app.T5EFT5 = uieditfield(app.T5P2, 'text');
            app.T5B6 = uibutton(app.T5P2, 'push');
            app.T5B6.ButtonPushedFcn = createCallbackFcn(app, @T5B6ButtonPushed, true);
            app.T5B6.Text = 'Finalizing the results';
            app.Label_14 = uilabel(app.T5P2);
            app.Label_14.Text = '(3)';
            app.T5CB2 = uicheckbox(app.T5P2);
            app.T5CB2.Text = 'Write pos file';
            app.T5CB2.Value = true;
            app.T5CB3 = uicheckbox(app.T5P2);
            app.T5CB3.ValueChangedFcn = createCallbackFcn(app, @T5CB3ValueChanged, true);
            app.T5CB3.Text = 'Add "other" atoms to the pos file';
            app.T5P6 = uipanel(app.Tab5);
            app.T5P6.Title = 'Dislocation-pair angle';
            app.DislocationloopIDvaluesExample39Label = uilabel(app.T5P6);
            app.DislocationloopIDvaluesExample39Label.Text = {'Dislocation loop ID values'; '(Example: 3,9)'};
            app.T5EFT14 = uieditfield(app.T5P6, 'text');
            app.T5B11 = uibutton(app.T5P6, 'push');
            app.T5B11.ButtonPushedFcn = createCallbackFcn(app, @T5B11ButtonPushed, true);
            app.T5B11.Text = 'Angles';
            app.T5P8 = uipanel(app.Tab5);
            app.T5P8.Title = 'Plotting combined IPF ';
            app.DislocationloopIDvaluesExample24567EditFieldLabel = uilabel(app.T5P8);
            app.DislocationloopIDvaluesExample24567EditFieldLabel.Text = {'Dislocation loop ID values'; '(Example: 2,4,5,6,7)'};
            app.T5EFT15 = uieditfield(app.T5P8, 'text');
            app.T5B12 = uibutton(app.T5P8, 'push');
            app.T5B12.ButtonPushedFcn = createCallbackFcn(app, @T5B12ButtonPushed, true);
            app.T5B12.Text = 'Plot';
            app.PlottitleoptionalEditFieldLabel = uilabel(app.T5P8);
            app.PlottitleoptionalEditFieldLabel.Text = 'Plot title (optional)';
            app.T5EFT16 = uieditfield(app.T5P8, 'text');
            app.Tab6 = uitab(app.TabGroup);
            app.Tab6.Title = '(4/2) Postprocessing (make a video)';
            app.T6P0 = uipanel(app.Tab6);
            app.T6F1 = uiaxes(app.T6P0);
            title(app.T6F1, '')
            xlabel(app.T6F1, '')
            ylabel(app.T6F1, '')
            app.T6F2 = uiaxes(app.T6P0);
            title(app.T6F2, '')
            xlabel(app.T6F2, '')
            ylabel(app.T6F2, '')
            app.T6F3 = uiaxes(app.T6P0);
            title(app.T6F3, '')
            xlabel(app.T6F3, '')
            ylabel(app.T6F3, '')
            app.T6F4 = uiaxes(app.T6P0);
            title(app.T6F4, '')
            xlabel(app.T6F4, '')
            ylabel(app.T6F4, '')
            app.T6F5 = uiaxes(app.T6P0);
            title(app.T6F5, '')
            xlabel(app.T6F5, '')
            ylabel(app.T6F5, '')
            app.T6L11 = uilabel(app.T6P0);
            app.T6L11.Text = '';
            app.T6L12 = uilabel(app.T6P0);
            app.T6L12.Text = '';
            app.T6L13 = uilabel(app.T6P0);
            app.T6L13.Text = '';
            app.T6L15 = uilabel(app.T6P0);
            app.T6L15.Text = '';
            app.T6F4_2 = uiaxes(app.T6P0);
            title(app.T6F4_2, '')
            xlabel(app.T6F4_2, '')
            ylabel(app.T6F4_2, '')
            app.T6F4_2.Visible = 'off';
            app.T6L6 = uilabel(app.Tab6);
            app.T6L6.Visible = 'off';
            app.T6L6.Text = {'Plot #1'; 'Original dataset'};
            app.T6L2 = uilabel(app.Tab6);
            app.T6L2.Visible = 'off';
            app.T6L2.Text = {'Plot #2'; 'Dislocations'};
            app.T6L3 = uilabel(app.Tab6);
            app.T6L3.Visible = 'off';
            app.T6L3.Text = {'Plot #3'; 'Clusters'};
            app.T6L4 = uilabel(app.Tab6);
            app.T6L4.Visible = 'off';
            app.T6L4.Text = {'Plot #4'; 'Three-color plot'};
            app.T6L5 = uilabel(app.Tab6);
            app.T6L5.Visible = 'off';
            app.T6L5.Text = {'Plot #5'; 'Other dense objects'};
            app.T6B1 = uibutton(app.Tab6, 'push');
            app.T6B1.ButtonPushedFcn = createCallbackFcn(app, @T6B1ButtonPushed, true);
            app.T6B1.Text = 'Initialize this tab';
            app.T6B2 = uibutton(app.Tab6, 'push');
            app.T6B2.ButtonPushedFcn = createCallbackFcn(app, @T6B2ButtonPushed, true);
            app.T6B2.Text = 'Plot';
            app.T6Lamp1 = uilamp(app.Tab6);
            app.T6L1 = uilabel(app.Tab6);
            app.T6L1.Text = {'Press the "Initialize this tab" button'; ''};
            app.T6P1 = uipanel(app.Tab6);
            app.T6P1.Title = 'Figure 1 settings';
            app.PointsizeLabel = uilabel(app.T6P1);
            app.PointsizeLabel.Text = 'Point size';
            app.T6EFT1 = uieditfield(app.T6P1, 'text');
            app.T6EFT1.Value = '3';
            app.PointcolorLabel = uilabel(app.T6P1);
            app.PointcolorLabel.Text = 'Point color';
            app.T6EFT2 = uieditfield(app.T6P1, 'text');
            app.T6EFT2.Value = '0,0,0';
            app.FontsizeLabel = uilabel(app.T6P1);
            app.FontsizeLabel.Text = 'Font size';
            app.T6EFT3 = uieditfield(app.T6P1, 'text');
            app.T6EFT3.Value = '11';
            app.FonttitlemultiplierLabel = uilabel(app.T6P1);
            app.FonttitlemultiplierLabel.Text = 'Font title multiplier';
            app.T6EFT4 = uieditfield(app.T6P1, 'text');
            app.T6EFT4.Value = '1.2';
            app.T6CB2 = uicheckbox(app.T6P1);
            app.T6CB2.Text = 'Show axes';
            app.T6CB4 = uicheckbox(app.T6P1);
            app.T6CB4.Text = 'Show scale bar';
            app.T6CB4.Value = true;
            app.ScalebarpositionnmLabel = uilabel(app.T6P1);
            app.ScalebarpositionnmLabel.Text = {'Scale bar '; 'position (nm)'};
            app.T6EFT5 = uieditfield(app.T6P1, 'text');
            app.T6EFT5.Value = '15,15,18';
            app.ScalebarsizenmLabel = uilabel(app.T6P1);
            app.ScalebarsizenmLabel.Text = 'Scale bar size (nm)';
            app.T6EFT6 = uieditfield(app.T6P1, 'text');
            app.T6EFT6.Value = '15';
            app.ScalebartextpositionnmLabel = uilabel(app.T6P1);
            app.ScalebartextpositionnmLabel.Text = 'Scale bar text position (nm)';
            app.T6EFT7 = uieditfield(app.T6P1, 'text');
            app.T6EFT7.Value = '1.8,2.5,0';
            app.ScalebartextfontLabel = uilabel(app.T6P1);
            app.ScalebartextfontLabel.Text = 'Scale bar text font ';
            app.T6EFT8 = uieditfield(app.T6P1, 'text');
            app.T6EFT8.Value = '12';
            app.T6BG1 = uibuttongroup(app.T6P1);
            app.T6BG1.SelectionChangedFcn = createCallbackFcn(app, @T6BG1SelectionChanged, true);
            app.T6BG1.Title = 'Title type';
            app.T6BG6_Intrinsic = uiradiobutton(app.T6BG1);
            app.T6BG6_Intrinsic.Text = 'Intrinsic';
            app.T6BG6_Intrinsic.Value = true;
            app.T6BG6_Fixed = uiradiobutton(app.T6BG1);
            app.T6BG6_Fixed.Text = 'Fixed';
            app.T6BG6_None = uiradiobutton(app.T6BG1);
            app.T6BG6_None.Text = 'None';
            app.T6CB30 = uicheckbox(app.T6P1);
            app.T6CB30.Text = 'Do not plot this figure';
            app.T6P6 = uipanel(app.Tab6);

            app.T6P6.Title = 'Make a vidoe';
            app.T6B3 = uibutton(app.T6P6, 'push');
            app.T6B3.ButtonPushedFcn = createCallbackFcn(app, @T6B3ButtonPushed, true);
            app.T6B3.Text = 'Capture the video';
            app.FromplotLabel = uilabel(app.T6P6);
            app.FromplotLabel.Text = 'From plot#';
            app.T6EF1 = uieditfield(app.T6P6, 'numeric');
            app.T6EF1.Value = 1;
            app.toplotLabel = uilabel(app.T6P6);
            app.toplotLabel.Text = 'to plot#';
            app.T6EF2 = uieditfield(app.T6P6, 'numeric');
            app.T6EF2.Value = 5;
            app.LeftmarginpixelLabel = uilabel(app.T6P6);
            app.LeftmarginpixelLabel.Text = 'Left margin (pixel)';
            app.T6EF3 = uieditfield(app.T6P6, 'numeric');
            app.T6EF3.Value = -8;
            app.RightmarginpixelLabel = uilabel(app.T6P6);
            app.RightmarginpixelLabel.Text = 'Right margin (pixel)';
            app.T6EF4 = uieditfield(app.T6P6, 'numeric');
            app.T6EF4.Value = 24;
            app.TopmarginpixelLabel = uilabel(app.T6P6);
            app.TopmarginpixelLabel.Text = 'Top margin (pixel)';
            app.T6EF5 = uieditfield(app.T6P6, 'numeric');
            app.T6EF5.Value = 26;
            app.BottommarginpixelLabel = uilabel(app.T6P6);
            app.BottommarginpixelLabel.Text = 'Bottom margin (pixel)';
            app.T6EF6 = uieditfield(app.T6P6, 'numeric');
            app.T6EF6.Value = -11;
            app.Framerate1sLabel = uilabel(app.T6P6);
            app.Framerate1sLabel.Text = 'Frame rate (1/s)';
            app.T6EF7 = uieditfield(app.T6P6, 'numeric');
            app.T6EF7.Value = 5;
            app.VideoqualityLabel = uilabel(app.T6P6);
            app.VideoqualityLabel.Text = 'Video quality (%)';
            app.T6EF8 = uieditfield(app.T6P6, 'numeric');
            app.T6EF8.Value = 100;
            app.NumberofcyclesLabel = uilabel(app.T6P6);
            app.NumberofcyclesLabel.Text = 'Number of cycles';
            app.T6EFT26 = uieditfield(app.T6P6, 'text');
            app.T6EFT26.Value = '2';
            app.T6EFT27Label = uilabel(app.T6P6);
            app.T6EFT27Label.Text = {'Rotation step'; 'size (degree)'};
            app.T6EFT27 = uieditfield(app.T6P6, 'text');
            app.T6EFT27.Value = '2';
            app.T6BG6 = uibuttongroup(app.T6P6);
            app.T6BG6.SelectionChangedFcn = createCallbackFcn(app, @T6BG6SelectionChanged, true);
            app.T6BG6.Title = 'Video type';
            app.T6BG6_Rotation = uiradiobutton(app.T6BG6);
            app.T6BG6_Rotation.Text = 'Rotation';
            app.T6BG6_Rotation.Value = true;
            app.T6BG6_Slice = uiradiobutton(app.T6BG6);
            app.T6BG6_Slice.Text = 'Slice';
            app.aLabel = uilabel(app.T6P6);
            app.aLabel.Text = '(3a)';
            app.BackgroundcolorEditFieldLabel = uilabel(app.T6P6);
            app.BackgroundcolorEditFieldLabel.Text = 'Background color';
            app.T6EFT28 = uieditfield(app.T6P6, 'text');
            app.T6EFT28.ValueChangedFcn = createCallbackFcn(app, @T6EFT28ValueChanged, true);
            app.T6EFT28.Value = '0,0,0';
            app.T6DD1_2Label = uilabel(app.T6P6);
            app.T6DD1_2Label.Text = 'Slice direction';
            app.T6DD1_2 = uidropdown(app.T6P6);
            app.T6DD1_2.Items = {'x-axis', 'y-axis', 'z-axis'};
            app.T6DD1_2.Value = 'x-axis';
            app.T6DD2_2Label = uilabel(app.T6P6);
            app.T6DD2_2Label.Text = 'Rotation axis';
            app.T6DD2_2 = uidropdown(app.T6P6);
            app.T6DD2_2.Items = {'x-axis', 'y-axis', 'z-axis'};
            app.T6DD2_2.Value = 'z-axis';
            app.T6DD3_2Label = uilabel(app.T6P6);
            app.T6DD3_2Label.Text = 'Rotation angle';
            app.T6DD3_2 = uidropdown(app.T6P6);
            app.T6DD3_2.Items = {'Azimuth', 'Elevation', 'Azimuth_Reverse', 'Elevation_Reverse', ''};
            app.T6DD3_2.Value = 'Azimuth';
            app.T6P2 = uipanel(app.Tab6);
            app.T6P2.Title = 'Figure 2 settings';
            app.SkeletonpointsizeLabel = uilabel(app.T6P2);
            app.SkeletonpointsizeLabel.Text = 'Skeleton-point size';
            app.T6EFT9 = uieditfield(app.T6P2, 'text');
            app.T6EFT9.Value = '8';
            app.OriginalpointsizeLabel = uilabel(app.T6P2);
            app.OriginalpointsizeLabel.Text = 'Original-point size';
            app.T6EFT10 = uieditfield(app.T6P2, 'text');
            app.T6EFT10.Value = '3';
            app.FontsizeLabel_2 = uilabel(app.T6P2);
            app.FontsizeLabel_2.Text = 'Font size';
            app.T6EFT11 = uieditfield(app.T6P2, 'text');
            app.T6EFT11.Value = '11';
            app.FonttitlemultiplierLabel_2 = uilabel(app.T6P2);
            app.FonttitlemultiplierLabel_2.Text = 'Font title multiplier';
            app.T6EFT12 = uieditfield(app.T6P2, 'text');
            app.T6EFT12.Value = '1.2';
            app.T6CB7 = uicheckbox(app.T6P2);
            app.T6CB7.Text = 'Show axes';
            app.ScalebarpositionnmLabel_2 = uilabel(app.T6P2);
            app.ScalebarpositionnmLabel_2.Text = 'Scale bar position (nm)';
            app.T6EFT13 = uieditfield(app.T6P2, 'text');
            app.T6EFT13.Value = '15,15,18';
            app.ScalebarsizenmLabel_2 = uilabel(app.T6P2);
            app.ScalebarsizenmLabel_2.Text = 'Scale bar size (nm)';
            app.T6EFT14 = uieditfield(app.T6P2, 'text');
            app.T6EFT14.Value = '15';
            app.ScalebartextpositionnmLabel_2 = uilabel(app.T6P2);
            app.ScalebartextpositionnmLabel_2.Text = 'Scale bar text position (nm)';
            app.T6EFT16 = uieditfield(app.T6P2, 'text');
            app.T6EFT16.Value = '1.8,2.5,0';
            app.ScalebartextfontLabel_2 = uilabel(app.T6P2);
            app.ScalebartextfontLabel_2.Text = 'Scale bar text font ';
            app.T6EFT15 = uieditfield(app.T6P2, 'text');
            app.T6EFT15.Value = '12';
            app.T6CB5 = uicheckbox(app.T6P2);
            app.T6CB5.Text = 'Show skeleton points';
            app.T6CB5.Value = true;
            app.T6CB6 = uicheckbox(app.T6P2);
            app.T6CB6.Text = 'Show original points';
            app.T6CB10 = uicheckbox(app.T6P2);
            app.T6CB10.Text = 'Original points in black color';
            app.T6CB10.Value = true;
            app.T6CB9 = uicheckbox(app.T6P2);
            app.T6CB9.Text = 'Show scale bar';
            app.T6CB9.Value = true;
            app.T6P3 = uipanel(app.Tab6);
            app.T6P3.Title = 'Figure 3 settings';
            app.SkeletonpointsizeLabel_2 = uilabel(app.T6P3);
            app.SkeletonpointsizeLabel_2.Text = 'Skeleton-point size';
            app.T6EFT9_2 = uieditfield(app.T6P3, 'text');
            app.T6EFT9_2.Value = '8';
            app.OriginalpointsizeLabel_2 = uilabel(app.T6P3);
            app.OriginalpointsizeLabel_2.Text = 'Original-point size';
            app.T6EFT10_2 = uieditfield(app.T6P3, 'text');
            app.T6EFT10_2.Value = '5';
            app.FontsizeLabel_3 = uilabel(app.T6P3);
            app.FontsizeLabel_3.Text = 'Font size';
            app.T6EFT11_2 = uieditfield(app.T6P3, 'text');
            app.T6EFT11_2.Value = '11';
            app.FonttitlemultiplierLabel_3 = uilabel(app.T6P3);
            app.FonttitlemultiplierLabel_3.Text = 'Font title multiplier';
            app.T6EFT12_2 = uieditfield(app.T6P3, 'text');
            app.T6EFT12_2.Value = '1.2';
            app.T6CB7_2 = uicheckbox(app.T6P3);
            app.T6CB7_2.Text = 'Show axes';
            app.T6CB9_2 = uicheckbox(app.T6P3);
            app.T6CB9_2.Text = 'Show scale bar';
            app.T6CB9_2.Value = true;
            app.ScalebarpositionnmLabel_3 = uilabel(app.T6P3);
            app.ScalebarpositionnmLabel_3.Text = 'Scale bar position (nm)';
            app.T6EFT13_2 = uieditfield(app.T6P3, 'text');
            app.T6EFT13_2.Value = '15,15,18';
            app.ScalebarsizenmLabel_3 = uilabel(app.T6P3);
            app.ScalebarsizenmLabel_3.Text = 'Scale bar size (nm)';
            app.T6EFT14_2 = uieditfield(app.T6P3, 'text');
            app.T6EFT14_2.Value = '15';
            app.ScalebartextpositionnmLabel_3 = uilabel(app.T6P3);
            app.ScalebartextpositionnmLabel_3.Text = 'Scale bar text position (nm)';
            app.T6EFT16_2 = uieditfield(app.T6P3, 'text');
            app.T6EFT16_2.Value = '1.8,2.5,0';
            app.ScalebartextfontLabel_3 = uilabel(app.T6P3);
            app.ScalebartextfontLabel_3.Text = 'Scale bar text font ';
            app.T6EFT15_2 = uieditfield(app.T6P3, 'text');
            app.T6EFT15_2.Value = '12';
            app.T6CB5_2 = uicheckbox(app.T6P3);
            app.T6CB5_2.Text = 'Show skeleton points';
            app.T6CB6_2 = uicheckbox(app.T6P3);
            app.T6CB6_2.Text = 'Show original points';
            app.T6CB6_2.Value = true;
            app.T6CB10_2 = uicheckbox(app.T6P3);
            app.T6CB10_2.Text = 'Original points in black color';
            app.T6BG3 = uibuttongroup(app.T6P3);
            app.T6BG3.SelectionChangedFcn = createCallbackFcn(app, @T6BG3SelectionChanged, true);
            app.T6BG3.Title = 'Title type';
            app.T6BG3_Intrinsic = uiradiobutton(app.T6BG3);
            app.T6BG3_Intrinsic.Text = 'Intrinsic';
            app.T6BG3_Intrinsic.Value = true;
            app.T6BG3_Fixed = uiradiobutton(app.T6BG3);
            app.T6BG3_Fixed.Text = 'Fixed';
            app.T6BG3_None = uiradiobutton(app.T6BG3);
            app.T6BG3_None.Text = 'None';
            app.T6P5 = uipanel(app.Tab6);
            app.T6P5.Title = 'Figure 5 settings';
            app.SkeletonpointsizeLabel_3 = uilabel(app.T6P5);
            app.SkeletonpointsizeLabel_3.Text = 'Skeleton-point size';
            app.T6EFT9_3 = uieditfield(app.T6P5, 'text');
            app.T6EFT9_3.Value = '8';
            app.OriginalpointsizeLabel_3 = uilabel(app.T6P5);
            app.OriginalpointsizeLabel_3.Text = 'Original-point size';
            app.T6EFT10_3 = uieditfield(app.T6P5, 'text');
            app.T6EFT10_3.Value = '5';
            app.FontsizeLabel_4 = uilabel(app.T6P5);
            app.FontsizeLabel_4.Text = 'Font size';
            app.T6EFT11_3 = uieditfield(app.T6P5, 'text');
            app.T6EFT11_3.Value = '11';
            app.FonttitlemultiplierLabel_4 = uilabel(app.T6P5);
            app.FonttitlemultiplierLabel_4.Text = 'Font title multiplier';
            app.T6EFT12_3 = uieditfield(app.T6P5, 'text');
            app.T6EFT12_3.Value = '1.2';
            app.T6CB7_3 = uicheckbox(app.T6P5);
            app.T6CB7_3.Text = 'Show axes';
            app.T6CB9_3 = uicheckbox(app.T6P5);
            app.T6CB9_3.Text = 'Show scale bar';
            app.T6CB9_3.Value = true;
            app.ScalebarpositionnmLabel_4 = uilabel(app.T6P5);
            app.ScalebarpositionnmLabel_4.Text = 'Scale bar position (nm)';
            app.T6EFT13_3 = uieditfield(app.T6P5, 'text');
            app.T6EFT13_3.Value = '15,15,18';
            app.ScalebarsizenmLabel_4 = uilabel(app.T6P5);
            app.ScalebarsizenmLabel_4.Text = 'Scale bar size (nm)';
            app.T6EFT14_3 = uieditfield(app.T6P5, 'text');
            app.T6EFT14_3.Value = '15';
            app.ScalebartextpositionnmLabel_4 = uilabel(app.T6P5);
            app.ScalebartextpositionnmLabel_4.Text = 'Scale bar text position (nm)';
            app.T6EFT16_3 = uieditfield(app.T6P5, 'text');
            app.T6EFT16_3.Value = '1.8,2.5,0';
            app.ScalebartextfontLabel_4 = uilabel(app.T6P5);
            app.ScalebartextfontLabel_4.Text = 'Scale bar text font ';
            app.T6EFT15_3 = uieditfield(app.T6P5, 'text');
            app.T6EFT15_3.Value = '12';
            app.T6CB5_3 = uicheckbox(app.T6P5);
            app.T6CB5_3.Text = 'Show skeleton points';
            app.T6CB6_3 = uicheckbox(app.T6P5);
            app.T6CB6_3.Text = 'Show original points';
            app.T6CB6_3.Value = true;
            app.T6CB10_3 = uicheckbox(app.T6P5);
            app.T6CB10_3.Text = 'Original points in black color';
            app.T6BG5 = uibuttongroup(app.T6P5);
            app.T6BG5.SelectionChangedFcn = createCallbackFcn(app, @T6BG5SelectionChanged, true);
            app.T6BG5.Title = 'Title type';
            app.T6BG5_Intrinsic = uiradiobutton(app.T6BG5);
            app.T6BG5_Intrinsic.Text = 'Intrinsic';
            app.T6BG5_Intrinsic.Value = true;
            app.T6BG5_Fixed = uiradiobutton(app.T6BG5);
            app.T6BG5_Fixed.Text = 'Fixed';
            app.T6BG5_None = uiradiobutton(app.T6BG5);
            app.T6BG5_None.Text = 'None';
            app.T6P4 = uipanel(app.Tab6);
            app.T6P4.Title = 'Figure 4 settings';
            app.DislocationpointsizeLabel = uilabel(app.T6P4);
            app.DislocationpointsizeLabel.Text = {'Dislocation '; 'point size'};
            app.T6EFT17_1 = uieditfield(app.T6P4, 'text');
            app.T6EFT17_1.Value = '6';
            app.DislocationpointcolorLabel = uilabel(app.T6P4);
            app.DislocationpointcolorLabel.Text = {'Dislocation'; 'point color'};
            app.T6EFT18_1 = uieditfield(app.T6P4, 'text');
            app.T6EFT18_1.Value = '1,0,0';
            app.FontsizeLabel_5 = uilabel(app.T6P4);
            app.FontsizeLabel_5.Text = 'Font size';
            app.T6EFT20 = uieditfield(app.T6P4, 'text');
            app.T6EFT20.Value = '11';
            app.FonttitlemultiplierLabel_5 = uilabel(app.T6P4);
            app.FonttitlemultiplierLabel_5.Text = {'Font title '; 'multiplier'};
            app.T6EFT21 = uieditfield(app.T6P4, 'text');
            app.T6EFT21.Value = '1.2';
            app.T6CB14 = uicheckbox(app.T6P4);
            app.T6CB14.Text = 'Show axes';
            app.T6CB15 = uicheckbox(app.T6P4);
            app.T6CB15.Text = 'Show title';
            app.T6CB15.Value = true;
            app.T6CB16 = uicheckbox(app.T6P4);
            app.T6CB16.Text = 'Show scale bar';
            app.T6CB16.Value = true;
            app.ScalebarpositionnmLabel_5 = uilabel(app.T6P4);
            app.ScalebarpositionnmLabel_5.Text = 'Scale bar position (nm)';
            app.T6EFT22 = uieditfield(app.T6P4, 'text');
            app.T6EFT22.Value = '15,15,18';
            app.ScalebarsizenmLabel_5 = uilabel(app.T6P4);
            app.ScalebarsizenmLabel_5.Text = 'Scale bar size (nm)';
            app.T6EFT23 = uieditfield(app.T6P4, 'text');
            app.T6EFT23.Value = '15';
            app.ScalebartextpositionnmLabel_5 = uilabel(app.T6P4);
            app.ScalebartextpositionnmLabel_5.Text = {'Scale bar text '; 'position (nm)'};
            app.T6EFT25 = uieditfield(app.T6P4, 'text');
            app.T6EFT25.Value = '1.8,2.5,0';
            app.ScalebartextfontLabel_5 = uilabel(app.T6P4);
            app.ScalebartextfontLabel_5.Text = 'Scale bar text font ';
            app.T6EFT24 = uieditfield(app.T6P4, 'text');
            app.T6EFT24.Value = '12';
            app.T6CB11 = uicheckbox(app.T6P4);
            app.T6CB11.Text = 'Show dislocations';
            app.T6CB11.Value = true;
            app.T6CB12 = uicheckbox(app.T6P4);
            app.T6CB12.Text = 'Show clusters';
            app.T6CB12.Value = true;
            app.ClusterpointsizeLabel = uilabel(app.T6P4);
            app.ClusterpointsizeLabel.Text = {'Cluster '; 'point size'};
            app.T6EFT17_2 = uieditfield(app.T6P4, 'text');
            app.T6EFT17_2.Value = '6';
            app.ClusterpointcolorLabel = uilabel(app.T6P4);
            app.ClusterpointcolorLabel.Text = {'Cluster'; 'point color'};
            app.T6EFT18_2 = uieditfield(app.T6P4, 'text');
            app.T6EFT18_2.Value = '0,0,1';
            app.T6CB13 = uicheckbox(app.T6P4);
            app.T6CB13.Text = 'Show other objects';
            app.T6CB13.Value = true;
            app.OtherobjectpointsizeLabel = uilabel(app.T6P4);
            app.OtherobjectpointsizeLabel.Text = {'Other-object '; 'point size'};
            app.T6EFT17_3 = uieditfield(app.T6P4, 'text');
            app.T6EFT17_3.Value = '6';
            app.OtherobjectpointcolorLabel = uilabel(app.T6P4);
            app.OtherobjectpointcolorLabel.Text = {'Other-object'; 'point color'};
            app.T6EFT18_3 = uieditfield(app.T6P4, 'text');
            app.T6EFT18_3.Value = '0,1,0';
            app.T6BG4 = uibuttongroup(app.T6P4);
            app.T6BG4.SelectionChangedFcn = createCallbackFcn(app, @T6BG4SelectionChanged, true);
            app.T6BG4.Title = 'Title type';
            app.T6BG4_Intrinsic = uiradiobutton(app.T6BG4);
            app.T6BG4_Intrinsic.Text = 'Intrinsic';
            app.T6BG4_Intrinsic.Value = true;
            app.T6BG4_Fixed = uiradiobutton(app.T6BG4);
            app.T6BG4_Fixed.Text = 'Fixed';
            app.T6BG4_None = uiradiobutton(app.T6BG4);
            app.T6BG4_None.Text = 'None';
            app.T6EFN11 = uieditfield(app.Tab6, 'numeric');
            app.T6EFN11.Value = 8;
            app.T2L2_4 = uilabel(app.Tab6);
            app.T2L2_4.Text = 'Slice thickness (nm)';
            app.T6S1 = uislider(app.Tab6);
            app.T6S1.Limits = [0 1];
            app.T6S1.ValueChangedFcn = createCallbackFcn(app, @T6S1ValueChanged, true);
            app.T6S1.Value = 0.5;
            app.T6B9 = uibutton(app.Tab6, 'push');
            app.T6B9.ButtonPushedFcn = createCallbackFcn(app, @T6B9ButtonPushed, true);
            app.T6B9.Text = 'Reset';
            app.SlicedirectionDropDownLabel_6 = uilabel(app.Tab6);
            app.SlicedirectionDropDownLabel_6.Text = 'Slice direction';
            app.T6DD1 = uidropdown(app.Tab6);
            app.T6DD1.Items = {'x-axis', 'y-axis', 'z-axis'};
            app.T6DD1.ValueChangedFcn = createCallbackFcn(app, @T6DD1ValueChanged, true);
            app.T6DD1.Value = 'x-axis';
            app.T6S2 = uislider(app.Tab6);
            app.T6S2.Limits = [0 360];
            app.T6S2.ValueChangedFcn = createCallbackFcn(app, @T6S2ValueChanged, true);
            app.RotationaxisLabel_7 = uilabel(app.Tab6);
            app.RotationaxisLabel_7.Text = 'Rotation axis';
            app.T6DD2 = uidropdown(app.Tab6);
            app.T6DD2.Items = {'x-axis', 'y-axis', 'z-axis'};
            app.T6DD2.Value = 'z-axis';
            app.RotationangleLabel_6 = uilabel(app.Tab6);
            app.RotationangleLabel_6.Text = 'Rotation angle';
            app.T6DD3 = uidropdown(app.Tab6);
            app.T6DD3.Items = {'Azimuth', 'Elevation', 'Azimuth_Reverse', 'Elevation_Reverse', ''};
            app.T6DD3.Value = 'Azimuth';
            app.T6S3 = uislider(app.Tab6);
            app.T6S3.Limits = [0.7 2];
            app.T6S3.ValueChangedFcn = createCallbackFcn(app, @T6S3ValueChanged, true);
            app.T6S3.Value = 1;
            app.T6B10 = uibutton(app.Tab6, 'push');
            app.T6B10.ButtonPushedFcn = createCallbackFcn(app, @T6B10ButtonPushed, true);
            app.T6B10.Text = 'Reset zoom';
            app.T6BG2 = uibuttongroup(app.Tab6);
            app.T6BG2.SelectionChangedFcn = createCallbackFcn(app, @T6BG2SelectionChanged, true);
            app.T6BG2.Title = 'Title type';
            app.T6BG2_Intrinsic = uiradiobutton(app.T6BG2);
            app.T6BG2_Intrinsic.Text = 'Intrinsic';
            app.T6BG2_Intrinsic.Value = true;
            app.T6BG2_Fixed = uiradiobutton(app.T6BG2);
            app.T6BG2_Fixed.Text = 'Fixed';
            app.T6BG2_None = uiradiobutton(app.T6BG2);
            app.T6BG2_None.Text = 'None';
            app.T6P7 = uipanel(app.Tab6);
            app.T6P7.Title = 'Save plots';
            app.T6P7.Visible = 'off';
            app.T6CB1 = uicheckbox(app.T6P7);
            app.T6CB1.ValueChangedFcn = createCallbackFcn(app, @T6CB1ValueChanged, true);
            app.T6CB1.Text = 'Show plot number';
            app.T6B4 = uibutton(app.T6P7, 'push');
            app.T6B4.ButtonPushedFcn = createCallbackFcn(app, @T6B4ButtonPushed, true);
            app.T6B4.Text = 'Save';
            app.bLabel = uilabel(app.T6P7);
            app.bLabel.Text = '(3b)';
            app.PlotExample13EditFieldLabel = uilabel(app.T6P7);
            app.PlotExample13EditFieldLabel.Text = {'Plot #'; '(Example: 1,3)'};
            app.T6EFT0 = uieditfield(app.T6P7, 'text');
            app.Label_15 = uilabel(app.Tab6);
            app.Label_15.Text = {'For saving a figure, set the '; '"Title type" to "Intrinsic".'; ''; 'For capturing a video, set '; 'the "Title type" to "Fixed".'};
            app.Label_16 = uilabel(app.Tab6);
            app.Label_16.Text = '(1)';
            app.Label_17 = uilabel(app.Tab6);
            app.Label_17.Text = '(2)';
            app.T6B11 = uibutton(app.Tab6, 'push');
            app.T6B11.ButtonPushedFcn = createCallbackFcn(app, @T6B11ButtonPushed, true);
            app.T6B11.Text = 'Get color values';
            % Show the figure after all components are created
            app.DSF.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DSF4

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.DSF)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.DSF)
        end
    end
end